classdef IVgetter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_4                         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NewMeasurementTab               matlab.ui.container.Tab
        LightControlPanel               matlab.ui.container.Panel
        SourceSwitch                    matlab.ui.control.ToggleSwitch
        SourceSwitchLabel               matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        AutoDarkLightSwitch             matlab.ui.control.ToggleSwitch
        AutoDarkLightSwitchLabel        matlab.ui.control.Label
        LightControlSwitch              matlab.ui.control.Switch
        LightControlSwitch_2Label       matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        ConnectToSourcemeterButton      matlab.ui.control.Button
        SampleInformationPanel          matlab.ui.container.Panel
        ParameterEditField              matlab.ui.control.EditField
        DeleteSelectedParametersButton  matlab.ui.control.Button
        AddParameterButton              matlab.ui.control.Button
        ParametesTextAreaLabel          matlab.ui.control.Label
        ParametersTable                 matlab.ui.control.Table
        Lamp                            matlab.ui.control.Lamp
        IlluminationSwitch              matlab.ui.control.ToggleSwitch
        StoreLastRunButton              matlab.ui.control.Button
        DiscardLastRunButton            matlab.ui.control.Button
        CommentsTextArea                matlab.ui.control.TextArea
        CommentsTextAreaLabel           matlab.ui.control.Label
        StepOfTheProcessEditField       matlab.ui.control.EditField
        StepOfTheProcessEditFieldLabel  matlab.ui.control.Label
        ChipEditField                   matlab.ui.control.EditField
        ChipEditFieldLabel              matlab.ui.control.Label
        WaferNameEditField              matlab.ui.control.EditField
        WaferNameEditFieldLabel         matlab.ui.control.Label
        RunQuickTestButton              matlab.ui.control.Button
        RunMeasurementButton            matlab.ui.control.Button
        QuickTestConfigurationPanel     matlab.ui.container.Panel
        PauseBetweenMeasurementssEditField_2  matlab.ui.control.NumericEditField
        PauseBetweenMeasurementssEditField_2Label  matlab.ui.control.Label
        ComplianceAEditField_2          matlab.ui.control.NumericEditField
        ComplianceAEditField_2Label     matlab.ui.control.Label
        VoltageStepEditField_2          matlab.ui.control.NumericEditField
        VoltageStepEditField_2Label     matlab.ui.control.Label
        VoltageStartEditField_2         matlab.ui.control.NumericEditField
        VoltageStartEditField_2Label    matlab.ui.control.Label
        VoltageEndEditField_2           matlab.ui.control.NumericEditField
        VoltageEndEditField_2Label      matlab.ui.control.Label
        MeasurementConfigurationPanel   matlab.ui.container.Panel
        PauseBetweenMeasurementssEditField  matlab.ui.control.NumericEditField
        PauseBetweenMeasurementssEditFieldLabel  matlab.ui.control.Label
        NumberOfMeasurementsEditField   matlab.ui.control.NumericEditField
        NumberOfMeasurementsEditFieldLabel  matlab.ui.control.Label
        ComplianceAEditField            matlab.ui.control.NumericEditField
        ComplianceAEditFieldLabel       matlab.ui.control.Label
        VoltageStepEditField            matlab.ui.control.NumericEditField
        VoltageStepEditFieldLabel       matlab.ui.control.Label
        VoltageStartEditField           matlab.ui.control.NumericEditField
        VoltageStartEditFieldLabel      matlab.ui.control.Label
        VoltageEndEditField             matlab.ui.control.NumericEditField
        VoltageEndEditFieldLabel        matlab.ui.control.Label
        IVcurveAxes                     matlab.ui.control.UIAxes
        DataManagementTab               matlab.ui.container.Tab
        ParametersStoreTable            matlab.ui.control.Table
        ClearAllDataButton              matlab.ui.control.Button
        ClearPlotSelectionButton        matlab.ui.control.Button
        AnalyzedataandsaveresultsinthesamefolderLabel  matlab.ui.control.Label
        AnalyzeDataCheckBox             matlab.ui.control.CheckBox
        DeleteUnnamedColumnsButton      matlab.ui.control.Button
        FolderEditField                 matlab.ui.control.EditField
        FolderEditFieldLabel            matlab.ui.control.Label
        ChooseFolderButton              matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        UpdatePlotButton                matlab.ui.control.Button
        HeaderTable                     matlab.ui.control.Table
        DataTable                       matlab.ui.control.Table
        PlotTab                         matlab.ui.container.Tab
        SummaryAxes                     matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        PhotocurrentcomparisonsPanel    matlab.ui.container.Panel
        Ratiocolumnrow1Label            matlab.ui.control.Label
        DifferenceAcolumnrowLabel       matlab.ui.control.Label
        PCratioTable                    matlab.ui.control.Table
        PCdiffTable                     matlab.ui.control.Table
        ChipDropDown                    matlab.ui.control.DropDown
        SelectChipLabel                 matlab.ui.control.Label
        WaferDropDown                   matlab.ui.control.DropDown
        SelectWaferLabel                matlab.ui.control.Label
        RefreshCalculationsButton       matlab.ui.control.Button
        PhotocurrentsTable              matlab.ui.control.Table
        DataStreamingTab                matlab.ui.container.Tab
        PitchHzSlider                   matlab.ui.control.Slider
        PitchHzSliderLabel              matlab.ui.control.Label
        BeepingControlPanel             matlab.ui.container.Panel
        MaxSignalmAEditField            matlab.ui.control.NumericEditField
        MaxSignalmAEditFieldLabel       matlab.ui.control.Label
        MinSignalmAEditField            matlab.ui.control.NumericEditField
        MinSignalmAEditFieldLabel       matlab.ui.control.Label
        BeepingLamp                     matlab.ui.control.Lamp
        BeepingSwitch                   matlab.ui.control.Switch
        BeepingSwitchLabel              matlab.ui.control.Label
        LatestvalueVEditField_2         matlab.ui.control.NumericEditField
        LatestvalueAEditField_2         matlab.ui.control.NumericEditField
        LatestvalueVEditField           matlab.ui.control.NumericEditField
        LatestvalueAEditField           matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BothcycleButton                 matlab.ui.control.RadioButton
        ConstantCurrentButton           matlab.ui.control.RadioButton
        ConstantVoltageButton           matlab.ui.control.RadioButton
        ComplianceVEditFieldLabel       matlab.ui.control.Label
        ComplianceVEditField            matlab.ui.control.NumericEditField
        ConstantVoltageVLabel_2         matlab.ui.control.Label
        ConstantCurrentAEditField       matlab.ui.control.NumericEditField
        StartStreamingButton            matlab.ui.control.StateButton
        TotalDurationsEditField         matlab.ui.control.NumericEditField
        TotalDurationsEditFieldLabel    matlab.ui.control.Label
        ComplianceALabel                matlab.ui.control.Label
        ComplianceAEditField_3          matlab.ui.control.NumericEditField
        ConstantVoltageVLabel           matlab.ui.control.Label
        ConstantvoltageVEditField       matlab.ui.control.NumericEditField
        NumberofMeasurementsEditField   matlab.ui.control.NumericEditField
        NumberofMeasurementsLabel       matlab.ui.control.Label
        IntervalBetweenMeasurementssEditField  matlab.ui.control.NumericEditField
        MeasurementFrequencysLabel      matlab.ui.control.Label
        AutoLightStreamSwitch           matlab.ui.control.ToggleSwitch
        MeasurementModeButtonGroup      matlab.ui.container.ButtonGroup
        FixedDurationButton             matlab.ui.control.ToggleButton
        FixedNumberButton               matlab.ui.control.ToggleButton
        ContinuousButton                matlab.ui.control.ToggleButton
        Panel                           matlab.ui.container.Panel
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        FileNameEditField_2             matlab.ui.control.EditField
        FileNameEditField_2Label        matlab.ui.control.Label
        FolderEditField_2               matlab.ui.control.EditField
        FolderEditField_2Label          matlab.ui.control.Label
        ChooseFolderButton_2            matlab.ui.control.Button
        LatestvalueVLabel_2             matlab.ui.control.Label
        LatestvaluemALabel_2            matlab.ui.control.Label
        LatestvaluemALabel              matlab.ui.control.Label
        LatestvalueVLabel               matlab.ui.control.Label
        UIStreamAxes                    matlab.ui.control.UIAxes
        ExternalBiasTab                 matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        LightSwitch                     matlab.ui.control.ToggleSwitch
        LightSwitchLabel                matlab.ui.control.Label
        Connectto2ndSMUButton           matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.StateButton
        StatusEditField_2               matlab.ui.control.EditField
        StatusEditField_2Label          matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        DescriptionEditField            matlab.ui.control.TextArea
        DescriptionTextArea_2Label      matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        ChooseFolderButton_3            matlab.ui.control.Button
        FileNameEditField_3             matlab.ui.control.EditField
        FileNameEditField_3Label        matlab.ui.control.Label
        FolderEditField_3               matlab.ui.control.EditField
        FolderEditField_3Label          matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Compliance2ndEditField          matlab.ui.control.NumericEditField
        NumberofMeasurementsEditField_2  matlab.ui.control.NumericEditField
        NumberofMeasurementsEditField_2Label  matlab.ui.control.Label
        ComplianceAEditField_4          matlab.ui.control.NumericEditField
        ComplianceAEditField_4Label     matlab.ui.control.Label
        VoltageStepEditField_3          matlab.ui.control.NumericEditField
        VoltageStepEditField_3Label     matlab.ui.control.Label
        VoltageEndEditField_3           matlab.ui.control.NumericEditField
        VoltageEndEditField_3Label      matlab.ui.control.Label
        VoltageStartEditField_3         matlab.ui.control.NumericEditField
        VoltageStartEditField_3Label    matlab.ui.control.Label
        SequenceSwitch                  matlab.ui.control.ToggleSwitch
        SequenceSwitchLabel             matlab.ui.control.Label
        BiasValueVEditField             matlab.ui.control.NumericEditField
        BiasValueVEditFieldLabel        matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        MaxBiasVEditField               matlab.ui.control.NumericEditField
        MaxBiasVEditFieldLabel          matlab.ui.control.Label
        MinBiasVEditFieldLabel          matlab.ui.control.Label
        MinBiasVEditField               matlab.ui.control.NumericEditField
        BiasOffsetVEditField            matlab.ui.control.NumericEditField
        BiasOffsetVEditFieldLabel       matlab.ui.control.Label
        UIBiasTable                     matlab.ui.control.Table
        BiasValuesButtonGroup           matlab.ui.container.ButtonGroup
        ListButton                      matlab.ui.control.RadioButton
        LogButton                       matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        SingleValueButton               matlab.ui.control.RadioButton
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ConnectedFlag=0; % Keeps track of whether app is connected to sourcemeter
        SimFlag=0; % Keeps track of whether we have entered sim mode
        RunningMeasurementFlag=0; % Try to prevent double measurements
        AutoLightControlFlag=0; % Keeps track of manual vs auto light control
        AutoLightDarkFlag=0; % Keeps track of whether it should do dark and light measurements automatically
        device; % handle for sourcemeter
        LastRunAutoLightFlag=0;
        DateTime; % Stores date and time stamps for each run

        StreamingFlag=0;
        StreamingData;
        StreamingHeader;
        StreamingDataUnsavedFlag=0;

        ExternalBiasUnsavedFlag=0;
        ExternalBiasData;
        ExternalBiasHeader;
        device2; % handle for sourcemeter
        
        TempVoltage=[]; % Temprorarily stores the voltage values of the last run
        TempCurrent=[]; % Temporarily stores the current values of the last run
        TableBuffer % Temporarily stores changing values from the header table
       
        UnsavedRunFlag=0; % Tracks whether there is unsaved runs
        UnsavedDataFlag=0; % Tracks whether there is unsaved data
        SavingFlag=0;

        RseriesWarningsFlag='No'; % Tracks whether to stop showing series resistance warnings
        
        ParametersIndividual;
        ParametersTotal=cell(0,0);
        
        Arduino; %Stores arduino object for auto shutter control
        
        % Sound variables
        Warning;
        WarningFs;
        Error;
        ErrorFs;
        Success;
        SuccessFs;
        
        % Data backup
        DataBackupFile;% get around using ctfroot by code at start [ctfroot,'\Backup.csv']; % Path for backup data
        
        % Colors
        Black = [0 0 0];
        Red = [0.85,0.33,0.1];
        Green = [0.39 0.83 0.07];
    end
    
    methods (Access = private)
        
        function UpdateTables(app,Wafer,Chip)
            if size(app.PhotocurrentsTable.Data,2)>0
                for ii=1:size(app.PhotocurrentsTable.Data,2)
                    Photocurrent.(app.PhotocurrentsTable.Data{1,ii}).(app.PhotocurrentsTable.Data{2,ii}).(app.PhotocurrentsTable.Data{3,ii})=app.PhotocurrentsTable.Data{4,ii};
                end
                Steps=fieldnames(Photocurrent.(Wafer).(Chip));
                PCvector=NaN(1,length(Steps));
                for ii=1:length(Steps)
                    PCvector(ii)=Photocurrent.(Wafer).(Chip).(Steps{ii});
                end
                app.PCdiffTable.ColumnName=Steps';
                app.PCdiffTable.RowName=Steps;
                app.PCdiffTable.Data=repmat(PCvector,length(PCvector),1)-PCvector';
                app.PCratioTable.ColumnName=Steps';
                app.PCratioTable.RowName=Steps;
                app.PCratioTable.Data=repmat(PCvector,length(PCvector),1)./PCvector'-1;
            end
        end
        
        %% This function is just to facilitate testing
        function SimulateSMU(app)
            app.RunMeasurementButton.Enable=0;
            app.RunQuickTestButton.Enable=0;
            Selection='';
            if app.UnsavedRunFlag==1
                soundsc(app.Warning,app.WarningFs);
                Selection=uiconfirm(app.UIFigure,'Continuing with the measurement will delete unsaved data, do you want to proceed anyways?','Unsaved Data','Options',{'Yes','No'});
            end
            if app.UnsavedRunFlag==0 || strcmp(Selection,'Yes')
                
                VoltageLine=app.VoltageStartEditField.Value:app.VoltageStepEditField.Value:app.VoltageEndEditField.Value;
                if sum(VoltageLine==0)==0
                    VoltageLine=[VoltageLine(VoltageLine<0) 0 VoltageLine(VoltageLine>0)];
                end
                app.TempVoltage=repmat(VoltageLine',1,app.NumberOfMeasurementsEditField.Value);
                app.TempCurrent=rand(size(app.TempVoltage));
                plot(app.IVcurveAxes,app.TempVoltage,app.TempCurrent)
                app.UnsavedRunFlag=1;
                app.StoreLastRunButton.Enable=1;
                app.DiscardLastRunButton.Enable=1;
            end
            app.RunMeasurementButton.Enable=1;
            app.RunQuickTestButton.Enable=1;
            
            pause(0.1)
        end
        
        function SaveDataToCSV(app,filename)
            try
                Parameters=app.ParametersStoreTable.RowName(:);
                RowNames=['Wafer';'Chip';'Step';'Illumination';Parameters;'Comments'];
                Header=[app.HeaderTable.Data(1:4,:);app.ParametersStoreTable.Data;app.HeaderTable.Data(5,:)];
                Units=[repmat({'Voltage (V)','Current (A)'},1,size(Header,2)),'Units'];
                Header=[reshape(repmat(Header,2,1),length(RowNames),[]) RowNames];
                Header=[Header;Units];
                Data=app.DataTable.Data;
                
                writecell(Header,[filename,'.csv']);
                writematrix(Data,[filename,'.csv'],'WriteMode','append');
            catch
                Parameters=app.ParametersStoreTable.RowName(:);
                Header=[app.HeaderTable.Data(1:4,:);app.ParametersStoreTable.Data;app.HeaderTable.Data(5,:)];
                Units=[repmat({'Voltage (V)','Current (A)'},1,size(Header,2)),'Units'];
                Header=[reshape(repmat(Header,2,1),length(RowNames),[]) RowNames];
                Header=[Header;Units];
                Data=app.DataTable.Data;

                writecell(Header,[filename,'_Header.csv']);
                writecell(Parameters,[filename,'_Parameters.csv']);
                writematrix(Data,[filename,'Data.csv']);
            end
        end
        
        function RecoverDataFromCSV(app)
            % Read data
            DataC=readcell(app.DataBackupFile);
            DataM=readmatrix(app.DataBackupFile);
            Chars=cellfun(@ischar,DataC);
            NumParameters=find(Chars(:,end)==0,1)-7;
            if NumParameters>0
                Parameters=DataC(5:4+NumParameters,end);
            end
            
            % Fill tables
            NumMeasurements=floor(size(DataM,2)/2);
            app.DataTable.Data=DataM(:,1:NumMeasurements*2);
            Numbers=cellfun(@num2str,num2cell(reshape([1:NumMeasurements;1:NumMeasurements],1,[])),'UniformOutput',false);
            Numbers=strcat(Numbers,repmat({'. '},1,NumMeasurements*2));
            app.DataTable.ColumnName=strcat(Numbers,DataC(NumParameters+6,1:end-1));
            app.DataTable.ColumnWidth=repmat({75},1,length(Numbers));
            
            DataC=DataC(:,1:2:end-1);
            Missing=cellfun(@ismissing,DataC,'UniformOutput',false);
            Comments=DataC(NumParameters+5,1:end);
            Comments(cellfun(@sum,Missing(NumParameters+5,1:end))==1)={''};
            Current=DataM(:,2:2:end);
            Isc=num2cell(Current((DataM(:,1:2:end)==0))');
            In=abs(DataM(:,2:2:end))==min(abs(DataM(:,2:2:end)));
            Voc=NaN(1,length(Isc));
            for ii=1:length(Voc)
                Voc(ii)=mean(DataM(In(:,ii),ii*2-1));
            end
            Voc=num2cell(Voc);
            app.HeaderTable.Data=[DataC(1:4,:);Comments;num2cell(false(1,length(Comments)));Isc;Voc];
            app.HeaderTable.ColumnWidth=num2cell(repmat(150,1,length(Comments)));
            
            if NumParameters>0
                ParameterValues=DataC(5:4+NumParameters,:);
                ParameterValues(cellfun(@sum,Missing(5:4+NumParameters,:))==1)={''};
                for ii=1:size(ParameterValues,1)
                    for jj=1:size(ParameterValues,2)
                        if isdatetime(ParameterValues{ii,jj})
                            Input=datestr(ParameterValues{ii,jj});
                        elseif isduration(ParameterValues{ii,jj})
                            Input=datestr(ParameterValues{ii,jj},'HH:MM:SS');
                        elseif isempty(ParameterValues{ii,jj})
                            Input='';
                        else
                            Input=ParameterValues{ii,jj};
                        end
                        app.ParametersStoreTable.Data{ii,jj}=Input;
                    end
                end
                app.ParametersStoreTable.RowName=Parameters;

                for ii=1:length(Parameters)
                    for jj=1:3
                        if jj==1
                            Input={false};
                        elseif jj==2
                            Input=Parameters(ii);
                        elseif jj==3
                            Input={''};
                        end
                        app.ParametersTable.Data(ii,jj)=Input;
                    end
                end
%                 app.ParametersTable.Data(:,1:2)=[false(size(Parameters)) Parameters repmat('']];
                
                app.ParametersTotal=app.ParametersStoreTable.Data;
                if length(Parameters)==1
                    app.ParametersIndividual{1,1}='';
                else
                    app.ParametersIndividual=repmat({''},length(Parameters),1);
                end
            end
            
            % Adjust values for app properties
            app.UnsavedDataFlag=1;
            
            % Save to struct
            
        end
        
        
        function DebugMessage(app,ME)
            try
                msg=['DEBUG:',newline,newline,'Error in command ',ME.stack(1).name,' in function ',ME.stack(2).name,' in line ',num2str(ME.stack(1).line),newline,newline,ME.message,newline,newline,newline,'Continuing without completing task.'];
                uialert(app.UIFigure,msg,'Error found','Icon','warning','modal',false);
                soundsc(app.Error,0.8*app.ErrorFs);
            catch
            end
        end
        
        function RseriesCheck(app,V,I)
            MinCurrent=0.015; % A
            MinVoltage=0.7; % V
            MaxR=50; % Ohm
            if ~strcmp(app.RseriesWarningsFlag,'Yes')
                Rseries=NaN(1,size(I,2));
                for ii=1:size(I,2)
                    try
                        if max(I)>=MinCurrent % A
                            In=find(I(:,ii)>=MinCurrent,1);
                            if length(I(:,ii))<In+1
                                In=In-1;
                            end
                            Line=polyfit(V(In-2:In+1),I(In-2:In+1),1);
                            Rseries(ii)=Line(1);
                        elseif max(V)>=MinVoltage % V
                            In=find(abs(V-MinVoltage)==min(abs(V-MinVoltage)),1);
                            if length(I(:,ii))<In+1
                                In=In-1;
                            end
                            Line=polyfit(V(In:In+1),I(In:In+1),1);
                            Rseries(ii)=Line(1);
                        else
                            Rseries(ii)=-inf;
                        end
                    catch
                    end
                end
                if sum(isnan(Rseries))
                    soundsc(app.Warning,app.WarningFs);
                    app.RseriesWarningsFlag=uiconfirm(app.UIFigure,{'Warning, unable to calculate series resistance. There was an error processing the data.';'';'Do you want to stop receiving these warnings? You would need to restart the app to turn them on.'},'Series resistance warning','Options',{'Yes','No','Close'});
                end
                if sum(Rseries==-inf)
                    soundsc(app.Warning,app.WarningFs);
                    app.RseriesWarningsFlag=uiconfirm(app.UIFigure,{'Warning, unable to calculate series resistance. Verify your measurement parameters.';'';'Do you want to stop receiving these warnings? You would need to restart the app to turn them on.'},'Series resistance warning','Options',{'Yes','No','Close'});
                end
                if sum(Rseries>MaxR)
                    soundsc(app.Warning,app.WarningFs);
                    app.RseriesWarningsFlag=uiconfirm(app.UIFigure,{['Warning, series resistance is high (>',num2str(MaxR),'Ohms)'];'';'Do you want to stop receiving these warnings? You would need to restart the app to turn them on.'},'Series resistance warning','Options',{'Yes','No','Close'});
                end
            end
        end
        
        function SwitchLight(app,Switch)
            if strcmp(app.SourceSwitch.Value,'Solar Simulator')
                if Switch==0
                    write(app.Arduino,'0','char');
                elseif Switch==1
                    write(app.Arduino,'1','char');
                end
            elseif strcmp(app.SourceSwitch.Value,'LED')
                if Switch==0
                    write(app.Arduino,'2','char');
                elseif Switch==1
                    write(app.Arduino,'3','char');
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app)
            try
                if isdeployed % Stand-alone mode.
                    [~, result] = system('path');
                    currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                else % MATLAB mode.
                    currentDir = pwd;
                end
                app.DataBackupFile=[currentDir,'\Backup.csv'];

                app.UIFigure.Name = 'IVgetter';
                app.FolderEditField.Value=[getenv('USERPROFILE'),'\Documents'];
                app.FolderEditField_2.Value=[getenv('USERPROFILE'),'\Documents'];
                app.FolderEditField_3.Value=[getenv('USERPROFILE'),'\Documents'];

                app.ParametersTable.Data={false,'Date',date;false,'Time',datestr(now,'HH:MM:SS')};
                app.ParametersIndividual{1,1}='';
                app.ParametersIndividual(end+1,:)={''};

                app.UIBiasTable.Data{1,1}=0;

                try
                    [app.Success,app.SuccessFs]=audioread('Success.mp3');
                    [app.Warning,app.WarningFs]=audioread('Warning.mp3');
                    [app.Error,app.ErrorFs]=audioread('Error.mp3');
                catch
                    app.Success=0;
                    app.Warning=0;
                    app.Error=0;
                    app.SuccessFs=1000;
                    app.WarningFs=1000;
                    app.ErrorFs=1000;
                end
                if exist(app.DataBackupFile, 'file')==2
                    soundsc(app.Warning,app.WarningFs);
                    Selection=uiconfirm(app.UIFigure,'The previous session did not close correctly, leaving unsaved data. Do you wish to restore this data to the current session? If not restored, data will be deleted forever.','Restore data','Options',{'Yes','No'});
                    if strcmp(Selection,'No')
                        delete(app.DataBackupFile);
                    elseif strcmp(Selection,'Yes')
                        RecoverDataFromCSV(app);
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: VoltageStartEditField
        function VoltageStartChanged(app, event)
            try
                value = app.VoltageStartEditField.Value;
                if value>app.VoltageStartEditField_2.Value
                    app.VoltageStartEditField_2.Value=value;
                end
                Diff=abs(app.VoltageStartEditField.Value-app.VoltageEndEditField.Value);
                app.VoltageStepEditField.Limits=[-Diff Diff];
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: VoltageEndEditField
        function VoltageEndChanged(app, event)
            try
                value = app.VoltageEndEditField.Value;
                if value<app.VoltageEndEditField_2.Value
                    app.VoltageEndEditField_2.Value=value;
                end
                Diff=abs(app.VoltageStartEditField.Value-app.VoltageEndEditField.Value);
                app.VoltageStepEditField.Limits=[-Diff Diff];
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: ComplianceAEditField
        function ComplianceChanged(app, event)
            try
                value = app.ComplianceAEditField.Value;
                app.ComplianceAEditField_2.Value=value;
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Callback function
        function SpacingChanged(app, event)
            try
                selectedButton = app.StepSpacingButtonGroup.SelectedObject;
                if selectedButton==app.LinearButton
                    app.StepSpacingButtonGroup_2.SelectedObject=app.LinearButton_2;
                elseif selectedButton==app.LogarithmicButton
                    app.StepSpacingButtonGroup_2.SelectedObject=app.LogarithmicButton_2;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: IlluminationSwitch
        function IlluminationChanged(app, event)
            try
                value = app.IlluminationSwitch.Value;
                if strcmp(value,'Dark')
                    app.Lamp.Enable=0;
                    app.Lamp.Color=app.Black;
                    if app.AutoLightControlFlag
                        SwitchLight(app,0);
                    end
                elseif strcmp(value,'Light')
                    app.Lamp.Color=app.Red;
                    app.Lamp.Enable=1;
                    if app.AutoLightControlFlag
                        SwitchLight(app,1);
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: ConnectToSourcemeterButton
        function ToggleConnect(app, event)
            try
                app.LightControlSwitch.Enable=0;
                app.RunningMeasurementFlag=0;
                if app.ConnectedFlag==0
                    try 
                        app.StatusEditField.Value='Connecting...';
                        app.StatusEditField.FontColor=[0,0,0];
                        pause(0.001);
                        Ports=serialportlist;
                        try
                            test=app.Arduino.Port;
                        catch
                            test='0';
                        end
                        for ii=1:length(Ports)
                            if  ~strcmp(Ports{ii},test)
                                app.device=serial(Ports{ii},'BaudRate',9600);
                                fopen(app.device);
                                IDN=query(app.device,'*IDN?');
                                if strcmp(IDN(1:end-1),'KEITHLEY INSTRUMENTS INC.,MODEL 2400,1212298,C30   Mar 17 2006 09:29:29/A02  /K/J')
                                    app.device.Tag='SMU';
                                    break
                                else
                                    fclose(app.device);
                                end
                            end
                        end
                        fprintf(app.device,'*RST');
                        fprintf(app.device,':FORM:ELEM VOLT,CURR');
                        fprintf(app.device,':CONF:CURR:DC');
                        fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField.Value)]);
                        fprintf(app.device,':SOUR:FUNC:MODE VOLT');
                        
                        fprintf(app.device,':OUTP OFF');
                        app.ConnectedFlag=1;
                        app.SimFlag=0;
                        app.StatusEditField.Value='Connected';
                        app.ConnectToSourcemeterButton.Text={'Disconnect from';'Sourcemeter'};
                        app.StatusEditField.FontColor=[0,1,0];
                        app.RunMeasurementButton.Text='Run Measurement';
                        app.RunQuickTestButton.Text='Run Quick Test';
                        app.RunMeasurementButton.Enable=1;
                        app.RunQuickTestButton.Enable=1;

                        app.StartStreamingButton.Enable=1;
                        app.Connectto2ndSMUButton.Enable=1;
                    catch
                        try
                            out=instrfindall;
                            for ii=1:numel(out)
                                if strcmp(out(ii).Tag,'SMU')
                                    Port=out(ii).Port;
                                    fclose(out(ii));
                                    app.device=serial(Port,'BaudRate',9600);
                                    fopen(app.device);
                                    IDN=query(app.device,'*IDN?');
                                    if strcmp(IDN(1:end-1),'KEITHLEY INSTRUMENTS INC.,MODEL 2401,1361980,A01 Aug 25 2011 12:57:43/A02  /S/K') || strcmp(IDN(1:end-1),'KEITHLEY INSTRUMENTS INC.,MODEL 2400,1212298,C30   Mar 17 2006 09:29:29/A02  /K/J')
                                        app.device.Tag='SMU';
                                        break
                                    else
                                        fclose(app.device);
                                    end
                                end
                            end
                            fprintf(app.device,'*RST');
                            fprintf(app.device,':FORM:ELEM VOLT,CURR');
                            fprintf(app.device,':CONF:CURR:DC');
                            fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField.Value)]);
                            fprintf(app.device,':SOUR:FUNC:MODE VOLT');
                            
                            fprintf(app.device,':OUTP OFF');
                            app.ConnectedFlag=1;
                            app.SimFlag=0;
                            app.StatusEditField.Value='Connected';
                            app.ConnectToSourcemeterButton.Text={'Disconnect from';'Sourcemeter'};
                            app.StatusEditField.FontColor=[0,1,0];
                            app.RunMeasurementButton.Text='Run Measurement';
                            app.RunQuickTestButton.Text='Run Quick Test';
                            app.RunMeasurementButton.Enable=1;
                            app.RunQuickTestButton.Enable=1;

                            app.StartStreamingButton.Enable=1;
                            app.Connectto2ndSMUButton.Enable=1;
                        catch
                            soundsc(app.Error,app.ErrorFs);
                            Selection=uiconfirm(app.UIFigure,'Error connecting to the sourcemeter. Do you want to simulate measurements instead?','Connection error','Options',{'Yes','No'});
                            if strcmp(Selection,'Yes')
                                app.StatusEditField.Value='Simulating';
                                app.StatusEditField.FontColor=[0,0,1];
                                app.SimFlag=1;
                                app.RunMeasurementButton.Text='Simulate Measurement';
                                app.RunQuickTestButton.Text='Simulate Quick Test';
                                app.RunMeasurementButton.Enable=1;
                                app.RunQuickTestButton.Enable=1;

                                app.StartStreamingButton.Enable=1;
                            else
                                app.StatusEditField.Value='Error';
                                app.StatusEditField.FontColor=[1,0,0];
                                app.SimFlag=0;
                                app.RunMeasurementButton.Enable=0;
                                app.RunQuickTestButton.Enable=0;

                                app.StartStreamingButton.Enable=0;
                                app.Connectto2ndSMUButton.Enable=0;
                            end
                        end
                    end
                elseif app.ConnectedFlag==1
                    fclose(app.device);
                    fclose(instrfind);
                    app.RunMeasurementButton.Enable=0;
                    app.RunQuickTestButton.Enable=0;
                    app.ConnectedFlag=0;
                    app.StatusEditField.Value='Disconnected';
                    app.ConnectToSourcemeterButton.Text={'Connect to';'Sourcemeter'};
                    app.StatusEditField.FontColor=[0,0,0];
                    app.SimFlag=0;

                    app.StartStreamingButton.Enable=0;
                    app.Connectto2ndSMUButton.Enable=0;
                    app.RunButton.Enable=0;
                end
                app.LightControlSwitch.Enable=1;
            catch ME
                DebugMessage(app,ME);
                app.LightControlSwitch.Enable=1;
            end
        end

        % Button pushed function: RunQuickTestButton
        function RunTest(app, event)
            try
                app.RunningMeasurementFlag=0;
                app.RunMeasurementButton.Enable=0;
                app.RunQuickTestButton.Enable=0;
                pause(0.1)
                VoltageLine=app.VoltageStartEditField_2.Value:app.VoltageStepEditField_2.Value:app.VoltageEndEditField_2.Value;
                NumMeasurements=1;
                if app.AutoLightDarkFlag
                    NumMeasurements=2;
                    app.IlluminationSwitch.Enable=0;
                end
                data=cell(length(VoltageLine),1);
                Voltage=NaN(length(data),NumMeasurements);
                Current=NaN(length(data),NumMeasurements);
                for jj=1:NumMeasurements
                    if app.AutoLightDarkFlag
                        Options={'Dark','Light'};
                        Colors={app.Black,app.Red};
                        
                        app.IlluminationSwitch.Value=Options{jj};
                        app.Lamp.Enable=jj-1;
                        app.Lamp.Color=Colors{jj};
                        SwitchLight(app,num2str(jj-1))
                        pause(0.5)
                    end
                    if ~app.SimFlag
                        try
                            fprintf(app.device,':SOUR:FUNC VOLT');
                        catch
                        end
                        fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_2.Value)]);
                        for ii=1:length(VoltageLine)
                            fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(VoltageLine(ii))]);
                            pause(app.PauseBetweenMeasurementssEditField_2.Value)
                            data{ii,1}=query(app.device,':MEAS:CURR?');
                        end
                        fprintf(app.device,':OUTP OFF');
                        for ii=1:length(data)
                            Voltage(ii,jj)=str2double(data{ii}(1:9))*10^(str2double(data{ii}(11:13)));
                            Current(ii,jj)=str2double(data{ii}(15:23))*10^(str2double(data{ii}(25:27)));
                        end
                    else
                        Il=(rand-0.5)/100000;
                        n=rand+1;
                        I0=abs((rand-0.5)/10000);
                        if strcmp(app.IlluminationSwitch.Value,'Light')
                            Il=Il-(rand/10000)-0.003;
                        end
                        Voltage(:,jj)=VoltageLine;
                        Current(:,jj)=I0.*(exp(Voltage(:,jj)./(0.026*n))-1)+Il;
                        Current(Current>app.ComplianceAEditField.Value)=app.ComplianceAEditField.Value;
                    end
                end
                if app.AutoLightDarkFlag
                    SwitchLight(app,0)
                end
                hold(app.IVcurveAxes,'off')
                cla(app.IVcurveAxes);
                resetplotview(app.IVcurveAxes,'InitializeCurrentView');
                plot(app.IVcurveAxes,Voltage,Current)
                RseriesCheck(app,Voltage,Current)
                
                app.RunMeasurementButton.Enable=1;
                app.RunQuickTestButton.Enable=1;
                
                if app.AutoLightDarkFlag
                    app.IlluminationSwitch.Value=Options{1};
                    app.Lamp.Enable=0;
                    app.Lamp.Color=Colors{1};
                    SwitchLight(app,0);
                    app.IlluminationSwitch.Enable=1;
                end
                pause(0.1)
            catch ME
                DebugMessage(app,ME);
                app.RunMeasurementButton.Enable=1;
                app.RunQuickTestButton.Enable=1;
                app.IlluminationSwitch.Enable=1;
            end
        end

        % Close request function: UIFigure
        function Close(app, event)
            try
                if app.UnsavedRunFlag==1 || app.UnsavedDataFlag==1
                    soundsc(app.Warning,app.WarningFs);
                    Selection=uiconfirm(app.UIFigure,'Continuing will delete unsaved data, do you want to proceed anyways?','Unsaved Data','Options',{'Yes','No'});
                    if strcmp(Selection,'Yes')
                        app.UnsavedRunFlag=0;
                        app.UnsavedDataFlag=0;
                        if exist(app.DataBackupFile, 'file')==2
                            delete(app.DataBackupFile);
                        end
                    end
                end
                if app.UnsavedRunFlag==0 && app.UnsavedDataFlag==0
                    try
                        fprintf(app.device,':OUTP OFF');
                        try
                            fprintf(app.device2,':OUTP OFF');
                        catch
                        end
                        fclose(instrfind);
                    catch              
                    end
                    if app.AutoLightControlFlag
                        try
                            SwitchLight(app,0);
                            delete(app.Arduino);
                        catch
                        end
                    end

                else
                    return
                end
                delete(app)
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: RunMeasurementButton
        function RunMeasurement(app, event)
            try
                if ~app.RunningMeasurementFlag
                    app.RunningMeasurementFlag=1;
                    app.RunMeasurementButton.Enable=0;
                    app.RunQuickTestButton.Enable=0;
                    pause(0.1)
                    
                    Selection='';
                    if app.UnsavedRunFlag==1
                        soundsc(app.Warning,app.WarningFs);
                        Selection=uiconfirm(app.UIFigure,'Continuing with the measurement will delete unsaved data, do you want to proceed anyways?','Unsaved Data','Options',{'Yes','No'});
                    end
                    if app.UnsavedRunFlag==0 || strcmp(Selection,'Yes')
                        app.UnsavedRunFlag=1;

                        app.ParametersIndividual{1,end}=date;
                        app.ParametersTable.Data(1,3)=app.ParametersIndividual(1,end);
                        app.ParametersIndividual{2,end}=datestr(now,'HH:MM:SS');
                        app.ParametersTable.Data(2,3)=app.ParametersIndividual(2,end);

                        if strcmp(app.AutoDarkLightSwitch.Value,'On')
                            app.LastRunAutoLightFlag=1;
                            NumMeasurements=2*app.NumberOfMeasurementsEditField.Value;
                        else
                            app.LastRunAutoLightFlag=0;
                            NumMeasurements=app.NumberOfMeasurementsEditField.Value;
                        end
                        VoltageLine=app.VoltageStartEditField.Value:app.VoltageStepEditField.Value:app.VoltageEndEditField.Value;
                        if sum(VoltageLine==0)==0
                            VoltageLine=[VoltageLine(VoltageLine<0) 0 VoltageLine(VoltageLine>0)];
                        end
                        
                        data=cell(length(VoltageLine),NumMeasurements);
                        cla(app.IVcurveAxes)
                        resetplotview(app.IVcurveAxes,'InitializeCurrentView');
                        hold(app.IVcurveAxes,'on')
                        app.TempVoltage=NaN(length(VoltageLine),NumMeasurements);
                        app.TempCurrent=NaN(length(VoltageLine),NumMeasurements);
                        ProgressBar=uiprogressdlg(app.UIFigure,"Title",'Measurement in Progress',"Message",['Run 1/',app.NumberOfMeasurementsEditField.Value],'ShowPercentage',1,'Value',0,'Cancelable',1);
                        if ~app.SimFlag
                            try
                                fprintf(app.device,':SOUR:FUNC VOLT');
                            catch
                            end
                            fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_2.Value)]);
                        end
                        app.DateTime=cell(2,NumMeasurements);
                        for jj=1:NumMeasurements
                            app.DateTime{1,jj}=date;
                            app.DateTime{2,jj}=datestr(now,'HH:MM:SS');
                            if app.LastRunAutoLightFlag
                                if jj==1
                                    SwitchLight(app,0);
                                    app.IlluminationSwitch.Value='Dark';
                                    app.Lamp.Enable=0;
                                    app.Lamp.Color=app.Black;
                                elseif jj==NumMeasurements/2+1
                                    SwitchLight(app,1);
                                    app.IlluminationSwitch.Value='Light';
                                    app.Lamp.Enable=1;
                                    app.Lamp.Color=app.Red;
                                    pause(1);
                                end
                            end
                            ProgressBar.Message=['Run ',num2str(jj),'/',num2str(NumMeasurements)];
                            for ii=1:length(VoltageLine)
                                if ~app.SimFlag
                                    fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(VoltageLine(ii))]);
                                    pause(app.PauseBetweenMeasurementssEditField.Value)
                                    data{ii,jj}=query(app.device,':MEAS:CURR?');
                                end
                                ProgressBar.Value=min(max((length(VoltageLine)*(jj-1)+ii)/(NumMeasurements*length(VoltageLine)),0),1);
                                if ProgressBar.CancelRequested
                                    close(ProgressBar);
                                    if ~app.SimFlag
                                        fprintf(app.device,':OUTP OFF');
                                    end
                                    app.RunMeasurementButton.Enable=1;
                                    app.RunQuickTestButton.Enable=1;
                                    app.RunningMeasurementFlag=0;
                                    return
                                end
                            end
                            soundsc(app.Success,app.SuccessFs);
                            if ~app.SimFlag
                                for ii=1:length(VoltageLine)
                                    app.TempVoltage(ii,jj)=str2double(data{ii,jj}(1:9))*10^(str2double(data{ii,jj}(11:13)));
                                    app.TempCurrent(ii,jj)=str2double(data{ii,jj}(15:23))*10^(str2double(data{ii,jj}(25:27)));
                                end
                            else
                                pause(app.PauseBetweenMeasurementssEditField.Value)
                                Il=(rand-0.5)/100000;
                                n=rand+1;
                                I0=abs((rand-0.5)/10000);
                                if strcmp(app.IlluminationSwitch.Value,'Light')
                                    Il=Il-(rand/1000)-0.003;
                                end
                                app.TempVoltage(:,jj)=VoltageLine;
                                app.TempCurrent(:,jj)=I0.*(exp(app.TempVoltage(:,jj)./(0.026*n))-1)+Il;
                                app.TempCurrent(app.TempCurrent>app.ComplianceAEditField.Value)=app.ComplianceAEditField.Value;
                            end
                           
                        end
                        if app.LastRunAutoLightFlag
                            SwitchLight(app,0);
                            app.IlluminationSwitch.Value='Dark';
                            app.Lamp.Enable=0;
                            app.Lamp.Color=app.Black;
                        end
                        if ~app.SimFlag
                            fprintf(app.device,':OUTP OFF');
                        end
                        soundsc(app.Success,1.2*app.SuccessFs);
                        plot(app.IVcurveAxes,app.TempVoltage,app.TempCurrent)
                        RseriesCheck(app,app.TempVoltage,app.TempCurrent)

                        app.StoreLastRunButton.Enable=1;
                        app.DiscardLastRunButton.Enable=1;
                    end
                    app.RunMeasurementButton.Enable=1;
                    app.RunQuickTestButton.Enable=1;
                    
                    pause(0.1)
                    app.RunningMeasurementFlag=0;
                end
            catch ME
                DebugMessage(app,ME);
                if exist('ProgressBar','var')
                    close(ProgressBar);
                end
                app.RunningMeasurementFlag=0;
                app.RunMeasurementButton.Enable=1;
                app.RunQuickTestButton.Enable=1;
            end
        end

        % Button pushed function: DiscardLastRunButton
        function DiscardLastRun(app, event)
            try
                app.TempVoltage=[];
                app.TempVoltage=[];
                app.UnsavedRunFlag=0;
                app.StoreLastRunButton.Enable=0;
                app.DiscardLastRunButton.Enable=0;
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: StoreLastRunButton
        function SaveLastRun(app, event)
            try
                if ~app.SavingFlag
                    if strcmp(app.WaferNameEditField.Value,'') || strcmp(app.ChipEditField.Value,'') || strcmp(app.StepOfTheProcessEditField.Value,'')
                        soundsc(app.Error,app.ErrorFs);
                        uialert(app.UIFigure,'Data not saved. Make sure you have filled out the wafer and chip names, and the current step of the process.','Incomplete Data');
                    else
                        app.SavingFlag=1;
                        app.WaferNameEditField.Enable=0;
                        app.ChipEditField.Enable=0;
                        app.StepOfTheProcessEditField.Enable=0;
                        app.CommentsTextArea.Enable=0;
                        app.IlluminationSwitch.Enable=0;
                        app.StoreLastRunButton.Enable=0;
                        app.DiscardLastRunButton.Enable=0;
                        pause(0.01)
                        
                        ProgressBar=uiprogressdlg(app.UIFigure,"Title",'Saving',"Message",'Please wait. Creating Header','Indeterminate','on','Value',0,'Cancelable',0);
                        %%
                        Voc=app.TempVoltage(find(abs(app.TempCurrent)==min(abs(app.TempCurrent)),1))';
                        if app.LastRunAutoLightFlag
                            Header=repmat({app.WaferNameEditField.Value;app.ChipEditField.Value;app.StepOfTheProcessEditField.Value;'Dark';app.CommentsTextArea.Value{1};false;0;0},1,size(app.TempCurrent,2)/2);
                            Header=[Header repmat({app.WaferNameEditField.Value;app.ChipEditField.Value;app.StepOfTheProcessEditField.Value;'Light';app.CommentsTextArea.Value{1};false;0;0},1,size(app.TempCurrent,2)/2)];
                        else
                            Header=repmat({app.WaferNameEditField.Value;app.ChipEditField.Value;app.StepOfTheProcessEditField.Value;app.IlluminationSwitch.Value;app.CommentsTextArea.Value{1};false;0;0},1,size(app.TempCurrent,2));
                        end
                        for ii=1:size(app.TempCurrent,2)
                            In=find(app.TempVoltage(:,ii)==0,1);
                            if In>1 && In<length(app.TempVoltage)
                                Line=polyfit(app.TempVoltage(In-1:In+1,ii),app.TempCurrent(In-1:In+1,ii),1);
                            elseif In<length(app.TempVoltage)
                                Line=polyfit(app.TempVoltage(1),app.TempCurrent(1),1);
                            elseif In>1
                                Line=polyfit(app.TempVoltage(end),app.TempCurrent(end),1);
                            end
                            Header{7,ii}=sprintf('%1.4e',1000.*Line(end));
                        end
                        Header(8,:)=num2cell(Voc);
                        ProgressBar.Message='Please wait. Compiling data.';
                        Data=reshape([app.TempVoltage;app.TempCurrent],size(app.TempVoltage,1),[]);
                        if isempty(app.HeaderTable.Data)
                            ProgressBar.Message='Please wait. Creating tables.';
                            app.HeaderTable.Data={app.WaferNameEditField.Value;app.ChipEditField.Value;app.StepOfTheProcessEditField.Value;app.IlluminationSwitch.Value;app.CommentsTextArea.Value{1};false;0;0};
                            app.HeaderTable.Data(1:size(Header,1),1:size(Header,2))=Header;
                            app.DataTable.Data=Data;
                            app.HeaderTable.ColumnWidth={150};
                            app.HeaderTable.ColumnWidth(1:size(Header,2))=repmat({150},1,size(Header,2));
                            app.DataTable.ColumnWidth={75};
                            app.DataTable.ColumnWidth(1:size(Data,2))=repmat({75},1,size(Data,2));
                        else
                            ProgressBar.Message='Please wait. Updating tables.';
                            app.HeaderTable.Data(:,end+1:end+size(Header,2))=Header;
                            if size(app.DataTable.Data,1)==size(Data,1)
                                app.DataTable.Data(:,end+1:end+size(Data,2))=Data;
                            elseif size(app.DataTable.Data,1)>size(Data,1)
                                Diff=size(app.DataTable.Data,1)-size(Data,1);
                                Data(end+1:end+Diff,:)=NaN(Diff,size(Data,2));
                                 app.DataTable.Data(:,end+1:end+size(Data,2))=Data;
                            elseif size(app.DataTable.Data,1)<size(Data,1)
                                Diff=size(Data,1)-size(app.DataTable.Data,1);
                                app.DataTable.Data(end+1:end+Diff,:)=NaN(Diff,size(app.DataTable.Data,2));
                                 app.DataTable.Data(:,end+1:end+size(Data,2))=Data;
                            end
                            app.HeaderTable.ColumnWidth(end+1:end+size(Header,2))=repmat({150},1,size(Header,2));
                            app.DataTable.ColumnWidth(end+1:end+size(Data,2))=repmat({75},1,size(Data,2));
                        end
                        
                        ProgressBar.Message='Please wait. Updating data table header.';
                        Num=strsplit(num2str(size(app.HeaderTable.Data,2)-size(Header,2)+1:size(app.HeaderTable.Data,2)));
                        Volt=repmat({'. Voltage'},1,size(Header,2));
                        Curr=repmat({'. Current'},1,size(Header,2));
                        DataNames=reshape([(cellfun(@(x,y) [x  ' ' y],Num,Volt,'un',0));(cellfun(@(x,y) [x  ' ' y],Num,Curr,'un',0))],1,[]);
                        if length(app.DataTable.ColumnName)<=1
                            app.DataTable.ColumnName(1:size(DataNames,2))=DataNames;
                        else
                            app.DataTable.ColumnName(end+1:end+size(DataNames,2))=DataNames;
                        end
                        
                        ProgressBar.Message='Please wait. Updating parameter table.';
                        if ~isempty(app.ParametersTable.Data)
                            Diff=size(app.ParametersIndividual,1)-size(app.ParametersTotal,1);
                            if Diff~=0
                                app.ParametersTotal=[app.ParametersTotal;cell(Diff,size(app.ParametersTotal,2))];
                            end
                            for ii=1:size(app.TempCurrent,2)
                                app.ParametersTotal(:,size(app.HeaderTable.Data,2)-size(app.TempCurrent,2)+ii)=[app.DateTime(:,ii);app.ParametersIndividual(3:end)];
                            end
    
                            app.ParametersStoreTable.Data=app.ParametersTotal;
                            app.ParametersStoreTable.RowName=app.ParametersTable.Data(:,2);
                        end
    
                        ProgressBar.Message='Please wait. Backing up data.';
                        SaveDataToCSV(app,app.DataBackupFile(1:end-4))
    
                        ProgressBar.Message='Please wait. Enabling controls.';
                        app.WaferNameEditField.Enable=1;
                        app.ChipEditField.Enable=1;
                        app.StepOfTheProcessEditField.Enable=1;
                        app.CommentsTextArea.Enable=1;
                        app.IlluminationSwitch.Enable=1;
                        app.UnsavedRunFlag=0;
                        app.UnsavedDataFlag=1;
    
                        close(ProgressBar);
                        app.SavingFlag=0;
                    end
                end
            catch ME
                DebugMessage(app,ME);
                if exist('ProgressBar','var')
                    close(ProgressBar);
                end
                app.WaferNameEditField.Enable=1;
                app.ChipEditField.Enable=1;
                app.StepOfTheProcessEditField.Enable=1;
                app.CommentsTextArea.Enable=1;
                app.IlluminationSwitch.Enable=1;
                app.StoreLastRunButton.Enable=1;
                app.DiscardLastRunButton.Enable=1;
                app.SavingFlag=0;
            end
        end

        % Button pushed function: ChooseFolderButton, 
        % ...and 2 other components
        function GetFolder(app, event)
            try
                Source=event.Source;
                Folder=uigetdir([getenv('USERPROFILE'),'\Documents'],'Choose a folder');
                if Folder
                    if Source==app.ChooseFolderButton
                        app.FolderEditField.Value=Folder;
                    elseif Source==app.ChooseFolderButton_2
                        app.FolderEditField_2.Value=Folder;
                    elseif Source==app.ChooseFolderButton_3
                        app.FolderEditField_3.Value=Folder;
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: SaveDataButton
        function SaveData(app, event)
            try
                if sum(strcmp(app.HeaderTable.Data(1,:),'')>0)
                    soundsc(app.Error,app.ErrorFs);
                    uialert(app.UIFigure,'Some of the samples have improper wafer names. Please delete those columns or add a valid name to them.','Data Not Saved','Icon','warning','modal',false);
                else
                    try
                        Summary=struct;
                        for ii=1:size(app.HeaderTable.Data,2)
                        end
                        OverwritingFlag=0;
                        if exist([app.FolderEditField.Value,'\',app.FileNameEditField.Value,'.mat'],'file')
                            soundsc(app.Warning,app.WarningFs);
                            Overwrite=uiconfirm(app.UIFigure,'Overwrite existing file?','File already exists','Options',{'Yes','No'},'DefaultOption','No','Icon','Warning');
                            if strcmp(Overwrite,'No')
                                OverwritingFlag=1;
                            elseif strcmp(Overwrite,'Yes')
                                OverwritingFlag=0;
                            end
                        end
                        if OverwritingFlag==0
                            ProgressBar=uiprogressdlg(app.UIFigure,"Title",'Saving',"Message",'Please wait','Indeterminate','on','Value',0,'Cancelable',0);
                            filename=[app.FolderEditField.Value,'\',app.FileNameEditField.Value];
    %                         save([filename,'.mat'],'Summary');
                            if app.AnalyzeDataCheckBox.Value==1
                                AnalyzeIVData(Summary,app.FolderEditField.Value,app.FileNameEditField.Value);
                                IVgetterMat2Xls(Summary,app.FolderEditField.Value,app.FileNameEditField.Value);
                            end
                            SaveDataToCSV(app,filename);
                            app.UnsavedDataFlag=0;
                            CSVtoMat(app.FileNameEditField.Value,app.FolderEditField.Value);
                            if exist(app.DataBackupFile, 'file')==2
                                delete(app.DataBackupFile);
                            end
                            close(ProgressBar)
                            soundsc(app.Success,app.SuccessFs);
                            uialert(app.UIFigure,'Data successfully saved.','Data Saved','Icon','success','modal',false);
                        end
                    catch
                        close(ProgressBar)
                        soundsc(app.Error,app.ErrorFs);
                        uialert(app.UIFigure,'Data not saved.','Data Not Saved','Icon','error','modal',false);
                    end
                end
            catch ME
                DebugMessage(app,ME);
                if exist('ProgressBar','var')
                    close(ProgressBar);
                end
            end
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlot(app, event)
            try
                hold(app.SummaryAxes,'off')
                cla(app.SummaryAxes)
                resetplotview(app.SummaryAxes,'InitializeCurrentView');
                hold(app.SummaryAxes,'on')
                Total=sum(cell2mat(app.HeaderTable.Data(6,:)));
                Counter=0;
                L=cell(1,Total);
                for ii=1:size(app.HeaderTable.Data,2)
                    if app.HeaderTable.Data{6,ii}
                        Counter=Counter+1;
                        plot(app.SummaryAxes,app.DataTable.Data(:,ii*2-1),app.DataTable.Data(:,ii*2),'color',colors(Counter,Total));
                        L{Counter}=[app.HeaderTable.Data{1,ii},' ',app.HeaderTable.Data{2,ii},' ',app.HeaderTable.Data{3,ii},' ',app.HeaderTable.Data{4,ii}];
                    end
                end
                legend(app.SummaryAxes,L,'Box','off','Location','eastoutside','Orientation','vertical','Interpreter','none')
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: RefreshCalculationsButton
        function RefreshCalculations(app, event)
            try
                Summary=struct;
                for ii=1:size(app.HeaderTable.Data,2)
                    try
                        Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Voltage=[Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Voltage app.DataTable.Data(:,ii*2-1)];
                        Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Current=[Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Current app.DataTable.Data(:,ii*2)];
                        in=find(app.DataTable.Data(:,ii*2-1)==0);
                        Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Current(in(2:end),end)=NaN;
                        Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Voltage(in(2:end),end)=NaN;
                    catch
                        Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Voltage=app.DataTable.Data(:,ii*2-1);
                        Summary.(app.HeaderTable.Data{1,ii}).(app.HeaderTable.Data{2,ii}).(app.HeaderTable.Data{3,ii}).(app.HeaderTable.Data{4,ii}).Current=app.DataTable.Data(:,ii*2);
                    end
                end
                app.WaferDropDown.Items=fieldnames(Summary);
                app.ChipDropDown.Items=fieldnames(Summary.(app.WaferDropDown.Items{1}));
                
                app.PhotocurrentsTable.Data=[];
                wafers=fieldnames(Summary);
                for ii=1:length(wafers)
                    chips=fieldnames(Summary.(wafers{ii}));
                    for jj=1:length(chips)
                        steps=fieldnames(Summary.(wafers{ii}).(chips{jj}));
                        for kk=1:length(steps)
                            illumination=fieldnames(Summary.(wafers{ii}).(chips{jj}).(steps{kk}));
                            if sum(strcmp(illumination,'Light'))==1 && sum(strcmp(illumination,'Dark'))==1
                                light=Summary.(wafers{ii}).(chips{jj}).(steps{kk}).Light.Current;
                                lightv=Summary.(wafers{ii}).(chips{jj}).(steps{kk}).Light.Voltage;
                                dark=Summary.(wafers{ii}).(chips{jj}).(steps{kk}).Dark.Current;
                                darkv=Summary.(wafers{ii}).(chips{jj}).(steps{kk}).Dark.Voltage;
                                if sum(sum(lightv(lightv(:,1)==0,:)))==0 && sum(sum(darkv(darkv(:,1)==0,:)))==0
                                    PC=mean(light(lightv(:,1)==0,:))-mean(dark(darkv(:,1)==0,:));
                                    PCstd=std(light(lightv(:,1)==0,:))+std(dark(darkv(:,1)==0,:));
                                    app.PhotocurrentsTable.Data=[app.PhotocurrentsTable.Data {wafers{ii};chips{jj};steps{kk};PC;PCstd}];
                                end
                            end
                        end
                    end
                end
                UpdateTables(app,wafers{1},chips{1});
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: WaferDropDown
        function SelectWafer(app, event)
            try
                value = app.WaferDropDown.Value;
                app.ChipDropDown.Items=fieldnames(app.summary.(value));
                app.ChipDropDown.Value=app.ChipDropDown.Items{1};
                UpdateTables(app,value,app.ChipDropDown.Value);
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: ChipDropDown
        function SelectChip(app, event)
            try
                value = app.ChipDropDown.Value;
                UpdateTables(app,app.WaferDropDown.Value,value);
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: DeleteUnnamedColumnsButton
        function DeleteColumns(app, event)
            try
                ProgressBar=uiprogressdlg(app.UIFigure,"Title",'Deleting columns',"Message",'Please wait','Indeterminate','on','Value',0,'Cancelable',0);
                keep=true(1,size(app.HeaderTable.Data,2));
                keepData=true(1,size(app.DataTable.Data,2));
                for ii=1:size(app.HeaderTable.Data,2)
                    if strcmp(app.HeaderTable.Data{1,ii},'')
                        keep(ii)=false;
                        keepData(2*ii-1)=false;
                        keepData(2*ii)=false;
                    else
                        keep(ii)=true;
                        keepData(2*ii-1)=true;
                        keepData(2*ii)=true;
                    end
                end
                app.HeaderTable.Data=app.HeaderTable.Data(:,keep);
                app.DataTable.Data=app.DataTable.Data(:,keepData);
                if ~isempty(app.ParametersStoreTable.Data)
                    app.ParametersStoreTable.Data=app.ParametersStoreTable.Data(:,keep);
                end
                Num=strsplit(num2str(1:size(app.HeaderTable.Data,2)));
                Volt=repmat({'. Voltage'},1,size(app.HeaderTable.Data,2));
                Curr=repmat({'. Current'},1,size(app.HeaderTable.Data,2));
                DataNames=reshape([(cellfun(@(x,y) [x  ' ' y],Num,Volt,'un',0));(cellfun(@(x,y) [x  ' ' y],Num,Curr,'un',0))],1,[]);
                app.DataTable.ColumnName=DataNames;

                close(ProgressBar)
            catch ME
                DebugMessage(app,ME);
                if exist('ProgressBar','var')
                    close(ProgressBar);
                end
            end
        end

        % Value changed function: ChipEditField, 
        % ...and 2 other components
        function ChangeIdField(app, event)
            try
                Field=event.Source;
                value = Field.Value;
                if ~isvarname(value) && ~strcmp(value,'')
                    Field.Value='';
                    soundsc(app.Warning,app.WarningFs);
                    uialert(app.UIFigure,'Improper variable name entered. Please use a valid tag (purely numeric tags and certain special characters are not allowed).','Invalid tag','Icon','warning');
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Cell edit callback: HeaderTable
        function ChangeIdTable(app, event)
            try
                indices = event.Indices;
                newData = event.NewData;
                if indices(1)==1
                    if ~isvarname(newData)
                        app.HeaderTable.Data(indices(1),indices(2))={''};
                    end
                elseif indices(1)<=4
                    if ~isvarname(newData)
                        app.HeaderTable.Data(indices(1),indices(2))=app.TableBuffer;
                        soundsc(app.Warning,app.WarningFs);
                        uialert(app.UIFigure,'Improper variable name entered. Please use a valid tag (purely numeric tags and certain special characters are not allowed).','Invalid tag','Icon','warning');
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Cell selection callback: HeaderTable
        function SelectIdTable(app, event)
            try
                indices = event.Indices;
                app.TableBuffer=app.HeaderTable.Data(indices(1),indices(2));
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: ClearPlotSelectionButton
        function ClearPlotSelection(app, event)
            try
                app.HeaderTable.Data(6,:)=repmat({false},1,size(app.HeaderTable.Data,2));
                pause(0.1);
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: ClearAllDataButton
        function ClearAllData(app, event)
            try
                if app.UnsavedRunFlag==1 || app.UnsavedDataFlag==1
                    soundsc(app.Warning,app.WarningFs);
                    Selection=uiconfirm(app.UIFigure,'Continuing will delete unsaved data, do you want to proceed anyways?','Unsaved Data','Options',{'Yes','No'});
                    if strcmp(Selection,'Yes')
                        app.UnsavedRunFlag=0;
                        app.UnsavedDataFlag=0;
                        if exist(app.DataBackupFile, 'file')==2
                            delete(app.DataBackupFile);
                        end
                    end
                end
                if app.UnsavedRunFlag==0 && app.UnsavedDataFlag==0
                    ProgressBar=uiprogressdlg(app.UIFigure,"Title",'Saving',"Message",'Please wait. Creating Header','Indeterminate','on','Value',0,'Cancelable',0);
                    app.HeaderTable.Data=[];
                    app.ParametersStoreTable.Data=[];
                    app.ParametersTotal={''};
                    app.ParametersIndividual={''};
                    app.DataTable.Data=[];
                    app.PhotocurrentsTable.Data=[];
                    app.PCdiffTable.Data=[];
                    app.PCratioTable.Data=[];
                    app.DataTable.ColumnName={''};
                    app.PCdiffTable.ColumnName={''};
                    app.PCratioTable.ColumnName={''};
                    cla(app.SummaryAxes);
                    cla(app.IVcurveAxes);
                    app.StoreLastRunButton.Enable=0;
                    app.DiscardLastRunButton.Enable=0;
                    close(ProgressBar);

                    app.ParametersTable.Data={false,'Date',date;false,'Time',datestr(now,'HH:MM:SS')};
                    app.ParametersIndividual{1,1}='';
                    app.ParametersIndividual(end+1,:)={''};
                end
            catch ME
                DebugMessage(app,ME);
                try
                    close(ProgressBar);
                catch
                end
            end
        end

        % Button pushed function: AddParameterButton
        function AddParameter(app, event)
            try
                app.ParametersTable.Data{end+1,1}=false;
                app.ParametersTable.Data{end,2}=app.ParameterEditField.Value;
                app.ParametersTable.Data{end,3}='';
                
                app.DeleteSelectedParametersButton.Enable=1;
                app.ParameterEditField.Value='';
                app.AddParameterButton.Enable=0;
                
                if isempty(app.ParametersIndividual)
                    app.ParametersIndividual{1,1}='';
                else
                    app.ParametersIndividual(end+1,:)={''};
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: ParameterEditField
        function ParameterEntered(app, event)
            try
                value = app.ParameterEditField.Value;
                if ~isempty(value)
                    app.AddParameterButton.Enable=1;
                else
                    app.AddParameterButton.Enable=0;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: DeleteSelectedParametersButton
        function DeleteParameters(app, event)
            try
                Keep=~cell2mat(app.ParametersTable.Data(:,1));
                if sum(Keep)<length(Keep)
                    if  isempty(app.ParametersTotal) || sum(sum(cellfun(@isempty,app.ParametersTotal(Keep,:))))==numel(app.ParametersTotal(Keep,:))
                        Selection='Yes';
                    else
                        Selection=uiconfirm(app.UIFigure,'Proceeding will delete parameters assigned to ALL measurements. Are you sure you want to continue?','Erasing Data','Options',{'Yes','No'});
                    end
                    if strcmp(Selection,'Yes')
                        if ~isempty(app.ParametersTotal)
                            app.ParametersTotal=app.ParametersTotal(Keep,:);
                            app.ParametersStoreTable.Data=app.ParametersTotal;
                            app.ParametersIndividual=app.ParametersIndividual(Keep,:);
                            app.ParametersStoreTable.RowName=app.ParametersTable.Data(:,2);
                        end
                        app.ParametersTable.Data=app.ParametersTable.Data(Keep,:);
                    end
                    if isempty(app.ParametersTable.Data)
                        app.DeleteSelectedParametersButton.Enable=0;
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Cell edit callback: ParametersTable
        function EditParametersTable(app, event)
            try
                indices = event.Indices;
                if indices(1)<3
                    if indices(2)==1
                        app.ParametersTable.Data{indices(1),1}=false;
                    elseif indices(2)==3
                        if indices(1)==1
                            app.ParametersTable.Data{1,indices(2)}=date;
                        elseif indices(1)==2
                            app.ParametersTable.Data{2,indices(2)}=datestr(now,'HH:MM:SS');
                        end
                    end
                elseif indices(2)==3
                    newData = event.NewData;
                    app.ParametersIndividual(indices(1),end)={newData};
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Cell edit callback: ParametersStoreTable
        function ParametersStoreTableEdited(app, event)
            try
                indices = event.Indices;
                newData = event.NewData;
                oldData = event.PreviousData;
                if indices(1)<3
                    app.ParametersStoreTable.Data(indices(1),indices(2))={oldData};
                else
                    app.ParametersTotal(indices(1),indices(2))={newData};
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changing function: ParameterEditField
        function EditingParameter(app, event)
            try
                app.AddParameterButton.Enable=1;
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Callback function
        function Sim(app, event)
            SimulateSMU(app)
        end

        % Value changed function: LightControlSwitch
        function ToggleLightControl(app, event)
            try
                value = app.LightControlSwitch.Value;
                app.ConnectToSourcemeterButton.Enable=0;
                if strcmp(value,'Manual')
                    SwitchLight(app,0);
                    app.IlluminationSwitch.Value='Dark';
                    app.Lamp.Enable=0;
                    app.Lamp.Color=app.Black;
                    app.AutoLightControlFlag=0;
                    app.AutoDarkLightSwitch.Enable=0;
                    app.AutoDarkLightSwitch.Value='Off';
                    app.AutoLightDarkFlag=0;
                    app.Lamp_2.Color=app.Black;
                    app.Lamp_2.Enable=0;
                    app.AutoLightStreamSwitch.Enable=0;
                    app.AutoLightStreamSwitch.Value='Fixed Light';
                    app.LightSwitch.Enable=0;
                    app.LightSwitch.Value='Fixed';
                    app.SourceSwitch.Value='Solar Simulator';
                    app.SourceSwitch.Enable=0;
                    try
                        delete(app.Arduino);
                    catch
                    end
                elseif strcmp(value,'Auto')
                    app.LightControlSwitch.Enable=0;
                    pause(0.1);
                    try
                        try
                            delete(app.Arduino);
                        catch
                        end
                        List=fliplr(serialportlist);
                        Counter=0;
                        ID=0;
                        try
                            test=app.device.Port;
                        catch
                            test='0';
                        end
                        while Counter<=length(List) && ~ID
                            Counter=Counter+1;
                            if strcmp(List{Counter},test)
                                Counter=Counter+1;
                            end
                            app.Arduino=serialport(List{Counter},9600);
                            try
                                write(app.Arduino,'9','char');
                                read(app.Arduino,11,'char');
                                flush(app.Arduino);
                                write(app.Arduino,'9','char');
                                ID=read(app.Arduino,11,'char');
                            catch
                                ID=0;
                            end
                            if strcmp(ID(5:end),'Arduino')
                                1;
                                SwitchLight(app,0);
                                app.AutoLightControlFlag=1;
                                app.AutoDarkLightSwitch.Enable=1;
                                app.LightControlSwitch.Enable=1;
                                
                                app.AutoLightStreamSwitch.Enable=1;
                                app.LightSwitch.Enable=1;

                                app.SourceSwitch.Enable=1;
                                Connected=1;
                                break;
                            else
                                delete(app.Arduino);
                                ID=0;
                                Connected=0;
                            end
                        end
                        if ~Connected
                            soundsc(app.Warning,app.WarningFs);
                            uialert(app.UIFigure,'Unable to automatically control the shutter. Check Arduino connection.','Arduino not found','Icon','warning','modal',false);
                        end
                    catch
                        app.LightControlSwitch.Value='Manual';
                        app.AutoLightControlFlag=0;
                        app.AutoDarkLightSwitch.Enable=0;
                        app.AutoDarkLightSwitch.Value='Off';
                        app.AutoLightDarkFlag=0;
                        app.Lamp_2.Color=app.Black;
                        app.Lamp_2.Enable=0;
                        app.AutoLightStreamSwitch.Enable=0;
                        app.SourceSwitch.Value='Solar Simulator';
                        app.SourceSwitch.Enable=0;
                        soundsc(app.Warning,app.WarningFs);
                        uialert(app.UIFigure,'Unable to automatically control the shutter. Check Arduino connection.','Error connecting to Arduino','Icon','warning','modal',false);
                    end
                    app.LightControlSwitch.Enable=1;
                end
                app.ConnectToSourcemeterButton.Enable=1;
            catch ME
                DebugMessage(app,ME);
                app.ConnectToSourcemeterButton.Enable=1;
            end
        end

        % Value changed function: AutoDarkLightSwitch
        function ToggleAutoDarkLight(app, event)
            try
                value = app.AutoDarkLightSwitch.Value;
                if strcmp(value,'Off')
                    app.AutoLightDarkFlag=0;
                    app.Lamp_2.Color=app.Black;
                    app.Lamp_2.Enable=0;
                elseif strcmp(value,'On')
                    app.AutoLightDarkFlag=1;
                    app.Lamp_2.Color=app.Green;
                    app.Lamp_2.Enable=1;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Selection changed function: MeasurementModeButtonGroup
        function SelectMeasurementMode(app, event)
            try
                selectedButton = app.MeasurementModeButtonGroup.SelectedObject;
                if strcmp(selectedButton.Text,'Continuous')
                    app.TotalDurationsEditField.Enable=0;
                    app.IntervalBetweenMeasurementssEditField.Enable=1;
                    app.NumberofMeasurementsEditField.Enable=0;

                    app.TotalDurationsEditField.Value=Inf;
                    app.NumberofMeasurementsEditField.Value=  app.NumberofMeasurementsEditField.Limits(2);
                elseif strcmp(selectedButton.Text,'Fixed Number') || strcmp(selectedButton.Text,'Fixed Duration')
                    app.TotalDurationsEditField.Enable=1;
                    app.IntervalBetweenMeasurementssEditField.Enable=1;
                    app.NumberofMeasurementsEditField.Enable=1;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: IntervalBetweenMeasurementssEditField, 
        % ...and 2 other components
        function ChangeStreamParameters(app, event)
            try
                item = event.Source;
                if app.ContinuousButton.Value==1
                    app.NumberofMeasurementsEditField.Value=100000;
                    app.TotalDurationsEditField.Value=100000*app.IntervalBetweenMeasurementssEditField.Value;
                elseif app.FixedNumberButton.Value==1
                    if item==app.IntervalBetweenMeasurementssEditField
                        app.TotalDurationsEditField.Value=app.NumberofMeasurementsEditField.Value*app.IntervalBetweenMeasurementssEditField.Value;
                    elseif item==app.TotalDurationsEditField
                        app.IntervalBetweenMeasurementssEditField.Value=max(app.TotalDurationsEditField.Value/app.NumberofMeasurementsEditField.Value,app.IntervalBetweenMeasurementssEditField.Limits(1));
                        app.NumberofMeasurementsEditField.Value=app.TotalDurationsEditField.Value/app.IntervalBetweenMeasurementssEditField.Value;
                    elseif item==app.NumberofMeasurementsEditField
                        app.TotalDurationsEditField.Value=app.NumberofMeasurementsEditField.Value*app.IntervalBetweenMeasurementssEditField.Value;
                    end
                elseif app.FixedDurationButton.Value==1
                    if item==app.IntervalBetweenMeasurementssEditField
                        app.NumberofMeasurementsEditField.Value=app.TotalDurationsEditField.Value/app.IntervalBetweenMeasurementssEditField.Value;
                    elseif item==app.NumberofMeasurementsEditField.Value
                        app.IntervalBetweenMeasurementssEditField.Value=max(app.TotalDurationsEditField.Value/app.NumberofMeasurementsEditField.Value,app.IntervalBetweenMeasurementssEditField.Limits(1));
                        app.NumberofMeasurementsEditField.Value=app.TotalDurationsEditField.Value/app.IntervalBetweenMeasurementssEditField.Value;
                    elseif item==app.TotalDurationsEditField
                        app.NumberofMeasurementsEditField.Value=app.TotalDurationsEditField.Value/app.IntervalBetweenMeasurementssEditField.Value;
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: StartStreamingButton
        function StartStreaming(app, event)
            try
                value = app.StartStreamingButton.Value;
                if value==1 && app.StreamingDataUnsavedFlag
                    soundsc(app.Warning,app.WarningFs);
                    Selection=uiconfirm(app.UIFigure,'Continuing with the data stream will delete unsaved data, do you want to proceed anyways?','Unsaved Data','Options',{'Yes','No'});
                    if strcmp(Selection,'Yes')
                        app.StreamingDataUnsavedFlag=0;
                    end
                end
                if value==1 && ~app.StreamingDataUnsavedFlag
                    app.StreamingFlag=1;
                    app.StartStreamingButton.Text='Stop Streaming';
                    app.ConnectToSourcemeterButton.Enable=0;
                    app.RunQuickTestButton.Enable=0;
                    app.RunMeasurementButton.Enable=0;
                    app.SaveButton.Enable=0;
                    
                    app.ButtonGroup.Enable='off';
                    app.ConstantvoltageVEditField.Enable=0;
                    app.ConstantCurrentAEditField.Enable=0;
                    app.ComplianceAEditField_3.Enable=0;
                    app.ComplianceVEditField.Enable=0;
                    app.AutoLightStreamSwitch.Enable=0;
                    app.ContinuousButton.Enable=0;
                    app.FixedNumberButton.Enable=0;
                    app.FixedDurationButton.Enable=0;
                    app.TotalDurationsEditField.Enable=0;
                    app.NumberofMeasurementsEditField.Enable=0;
                    app.IntervalBetweenMeasurementssEditField.Enable=0;

                    NumMeasurements=app.NumberofMeasurementsEditField.Value;
                    Interval=app.IntervalBetweenMeasurementssEditField.Value;

                    Counter=0;
                    Columns=1;
                    if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                        Columns=Columns*2;
                    end
                    if app.ButtonGroup.SelectedObject==app.BothcycleButton
                        Columns=Columns*2;
                    end
                    if app.ContinuousButton.Value
                        Time=NaN(100000,1);
                        YData=NaN(100000,Columns);
                    else
                        Time=NaN(NumMeasurements,1);
                        YData=NaN(NumMeasurements,Columns);
                    end
                    if ~app.SimFlag
                        if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton || app.ButtonGroup.SelectedObject==app.BothcycleButton
                            try
                                fprintf(app.device,':SOUR:FUNC VOLT');
                            catch
                            end
                            fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_3.Value)]);
                            fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(app.ConstantvoltageVEditField.Value)]);
                        elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                             try
                                fprintf(app.device,':SOUR:FUNC CURR');
                            catch
                            end
                            fprintf(app.device,[':SENS:VOLT:PROT:LEV ',num2str(app.ComplianceVEditField.Value)]);
                            fprintf(app.device,['SOUR:CURR:LEV:IMM:AMPL ',num2str(app.ConstantCurrentAEditField.Value)]);
                        end
                    end
                    if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                        SwitchLight(app,0);
                        pause(0.1)
                    end
                    cla(app.UIStreamAxes);
                    Pitch=app.PitchHzSlider.Value;
                    Min=app.MinSignalmAEditField.Value;
                    Max=app.MaxSignalmAEditField.Value;
                    Interval=app.IntervalBetweenMeasurementssEditField.Value;
                    tic;
                    LastPlot=0;
                    StartDate=date;
                    StartTime=datestr(now,'HH:MM:SS');
                    while Counter<NumMeasurements && app.StreamingFlag
                        Counter=Counter+1;
                        pause(max(Interval*Counter-toc,0));
                        if ~app.SimFlag
                            if strcmp(app.AutoLightStreamSwitch.Value,'Fixed Light')
                                if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton
                                    data{1}=query(app.device,':MEAS:CURR?');
                                elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                                    data{1}=query(app.device,':MEAS:VOLT?');
                                elseif app.ButtonGroup.SelectedObject==app.BothcycleButton
                                    data{1}=query(app.device,':MEAS:CURR?');
                                    try
                                        fprintf(app.device,':SOUR:FUNC CURR');
                                    catch
                                    end
                                    fprintf(app.device,[':SENS:VOLT:PROT:LEV ',num2str(app.ComplianceVEditField.Value)]);
                                    fprintf(app.device,['SOUR:CURR:LEV:IMM:AMPL ',num2str(app.ConstantCurrentAEditField.Value)]);
                                    pause (0.05);
                                    data{2}=query(app.device,':MEAS:VOLT?');
                                    try
                                        fprintf(app.device,':SOUR:FUNC VOLT');
                                    catch
                                    end
                                    fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_3.Value)]);
                                    fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(app.ConstantvoltageVEditField.Value)]);
                                end
                            elseif strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                                if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton
                                    data{1}=query(app.device,':MEAS:CURR?');
                                    SwitchLight(app,1);
                                    pause(0.1)
                                    data{2}=query(app.device,':MEAS:CURR?');
                                    SwitchLight(app,0);
                                elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                                    data{1}=query(app.device,':MEAS:VOLT?');
                                    SwitchLight(app,1);
                                    pause(0.1)
                                    data{2}=query(app.device,':MEAS:VOLT?');
                                    SwitchLight(app,0);
                                elseif app.ButtonGroup.SelectedObject==app.BothcycleButton
                                    data{1}=query(app.device,':MEAS:CURR?');
                                    try
                                        fprintf(app.device,':SOUR:FUNC CURR');
                                    catch
                                    end
                                    fprintf(app.device,[':SENS:VOLT:PROT:LEV ',num2str(app.ComplianceVEditField.Value)]);
                                    fprintf(app.device,['SOUR:CURR:LEV:IMM:AMPL ',num2str(app.ConstantCurrentAEditField.Value)]);
                                    pause (0.05);
                                    data{2}=query(app.device,':MEAS:VOLT?');
                                    try
                                        fprintf(app.device,':SOUR:FUNC VOLT');
                                    catch
                                    end
                                    fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_3.Value)]);
                                    fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(app.ConstantvoltageVEditField.Value)]);

                                    SwitchLight(app,1);
                                    pause(0.1)

                                    data{3}=query(app.device,':MEAS:CURR?');
                                    try
                                        fprintf(app.device,':SOUR:FUNC CURR');
                                    catch
                                    end
                                    fprintf(app.device,[':SENS:VOLT:PROT:LEV ',num2str(app.ComplianceVEditField.Value)]);
                                    fprintf(app.device,['SOUR:CURR:LEV:IMM:AMPL ',num2str(app.ConstantCurrentAEditField.Value)]);
                                    pause (0.05);
                                    data{4}=query(app.device,':MEAS:VOLT?');
                                    try
                                        fprintf(app.device,':SOUR:FUNC VOLT');
                                    catch
                                    end
                                    fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_3.Value)]);
                                    fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(app.ConstantvoltageVEditField.Value)]);

                                    SwitchLight(app,0);
                                end
                            end
                            if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton
                                    YData(Counter,1)=str2double(data{1}(15:23))*10^(str2double(data{1}(25:27)));
                                if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                                    YData(Counter,2)=str2double(data{2}(15:23))*10^(str2double(data{2}(25:27)));
                                end
                            elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                                YData(Counter,1)=str2double(data{1}(1:9))*10^(str2double(data{1}(11:13)));
                                if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                                    YData(Counter,2)=str2double(data{2}(1:9))*10^(str2double(data{2}(11:13)));
                                end
                            elseif app.ButtonGroup.SelectedObject==app.BothcycleButton 
                                YData(Counter,1)=str2double(data{1}(15:23))*10^(str2double(data{1}(25:27)));
                                YData(Counter,2)=str2double(data{2}(1:9))*10^(str2double(data{2}(11:13)));
                                if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                                    YData(Counter,3)=str2double(data{3}(15:23))*10^(str2double(data{3}(25:27)));
                                    YData(Counter,4)=str2double(data{4}(1:9))*10^(str2double(data{4}(11:13)));
                                end
                            end
                        else
                            YData(Counter,:)=0.001*rand(1,Columns);
                        end
                        Time(Counter)=toc;

                        if strcmp(app.AutoLightStreamSwitch.Value,'Fixed Light')
                            if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton
                                app.LatestvalueAEditField.Value=1000.*YData(Counter,1);
                            elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                                app.LatestvalueVEditField.Value=YData(Counter,1);
                            elseif app.ButtonGroup.SelectedObject==app.BothcycleButton
                                app.LatestvalueAEditField.Value=1000.*YData(Counter,1);
                                app.LatestvalueVEditField.Value=YData(Counter,2);
                            end
                        elseif strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                            if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton
                                app.LatestvalueAEditField.Value=1000.*YData(Counter,1);
                                app.LatestvalueAEditField_2.Value=1000.*YData(Counter,2);
                            elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                                app.LatestvalueVEditField.Value=YData(Counter,1);
                                app.LatestvalueVEditField_2.Value=YData(Counter,2);
                            elseif app.ButtonGroup.SelectedObject==app.BothcycleButton
                                app.LatestvalueAEditField.Value=1000.*YData(Counter,1);
                                app.LatestvalueVEditField.Value=YData(Counter,2);
                                app.LatestvalueAEditField_2.Value=1000.*YData(Counter,3);
                                app.LatestvalueVEditField_2.Value=YData(Counter,4);
                            end
                        end
                        if strcmp(app.BeepingSwitch.Value,'On')
                            if 1000*YData(Counter,1)>Max
                                Ratio=0;
                            elseif 1000*YData(Counter,1)<Min
                                Ratio=1;
                            else
                                Ratio=abs((1000.*YData(Counter,1)-Max)/(Max-Min));
                            end
                            Ratio=max(min(Ratio,1),0);
                            NBeats=round(15.*log10(10.*Ratio));
                            NBeats=max(1,NBeats);
                            t=1:2*NBeats*round((Interval*1000/2)/NBeats);
                            y=sin(t.*Pitch./(2*pi()));
                            Y=y.*reshape(repmat([1 0],round((Interval*1000/2)/NBeats),NBeats),1,[]);
                            soundsc(Y,1000)
                        end
                        if Interval>=1 || (Time(Counter)-LastPlot)>=5
                            plot(app.UIStreamAxes,Time',1000.*YData')
                            LastPlot=Time(Counter);
                        end
                    end
                    if ~app.SimFlag
                        fprintf(app.device,':OUTP OFF');
                    end
                    plot(app.UIStreamAxes,Time',1000.*YData')
                    app.StartStreamingButton.Value=0;
                    app.StartStreamingButton.Text='Start Streaming';
                    app.ConnectToSourcemeterButton.Enable=1;
                    app.RunQuickTestButton.Enable=1;
                    app.RunMeasurementButton.Enable=1;

                    app.StreamingData=[Time(1:Counter) YData(1:Counter,:)];
                    if app.ButtonGroup.SelectedObject==app.ConstantVoltageButton
                        app.StreamingHeader={'Constant Voltage',app.ConstantvoltageVEditField.Value,'V'};
                        app.StreamingHeader=[app.StreamingHeader;{'Compliance',app.ComplianceAEditField_3.Value,'A'}];
                        app.StreamingHeader=[app.StreamingHeader;{'Measurement started:',StartDate,StartTime}];
                        app.StreamingHeader=[app.StreamingHeader;{'Measurement ended:',date,datestr(now,'HH:MM:SS')}];
                        if strcmp(app.AutoLightStreamSwitch.Value,'Fixed Light')
                            app.StreamingHeader=[app.StreamingHeader;{'Time (s)','Current (A)',''}];
                        else
                            app.StreamingHeader=[app.StreamingHeader;{'Time (s)','Dark Current (A)','Light Current (A)'}];
                        end
                    elseif app.ButtonGroup.SelectedObject==app.ConstantCurrentButton
                        app.StreamingHeader={'Constant Current',app.ConstantCurrentAEditField.Value,'A'};
                        app.StreamingHeader=[app.StreamingHeader;{'Compliance',app.ComplianceVEditField.Value,'V'}];
                        app.StreamingHeader=[app.StreamingHeader;{'Measurement started:',StartDate,StartTime}];
                        app.StreamingHeader=[app.StreamingHeader;{'Measurement ended:',date,datestr(now,'HH:MM:SS')}];
                        if strcmp(app.AutoLightStreamSwitch.Value,'Fixed Light')
                            app.StreamingHeader=[app.StreamingHeader;{'Time (s)','Voltage (V)',''}];
                        else
                            app.StreamingHeader=[app.StreamingHeader;{'Time (s)','Dark Voltage (V)','Light Voltage (V)'}];
                        end
                    elseif app.ButtonGroup.SelectedObject==app.BothcycleButton
                        app.StreamingHeader={'Constant Voltage',app.ConstantvoltageVEditField.Value,'V'};
                        app.StreamingHeader=[app.StreamingHeader;{'Compliance',app.ComplianceAEditField_3.Value,'A'}];
                        app.StreamingHeader=[app.StreamingHeader;{'Constant Current',app.ConstantCurrentAEditField.Value,'A'}];
                        app.StreamingHeader=[app.StreamingHeader;{'Compliance',app.ComplianceVEditField.Value,'V'}];
                        app.StreamingHeader=[app.StreamingHeader;{'Measurement started:',StartDate,StartTime}];
                        app.StreamingHeader=[app.StreamingHeader;{'Measurement ended:',date,datestr(now,'HH:MM:SS')}];
                        if strcmp(app.AutoLightStreamSwitch.Value,'Fixed Light')
                            app.StreamingHeader=[app.StreamingHeader;{'Time (s)','Current (A)','Voltage (V)'}];
                        else
                            app.StreamingHeader=[app.StreamingHeader repmat({''},6,2)];
                            app.StreamingHeader=[app.StreamingHeader;{'Time (s)','Dark Current (A)','Dark Voltage (V)','Light Current (A)','Light Voltage (V)'}];
                        end
                    end
                    app.StreamingDataUnsavedFlag=1;
                    app.SaveButton.Enable=1;

                    app.ButtonGroup.Enable='on';
                    app.ConstantvoltageVEditField.Enable=1;
                    app.ConstantCurrentAEditField.Enable=1;
                    app.ComplianceAEditField_3.Enable=1;
                    app.ComplianceVEditField.Enable=1;
                    app.AutoLightStreamSwitch.Enable=1;
                    app.ContinuousButton.Enable=1;
                    app.FixedNumberButton.Enable=1;
                    app.FixedDurationButton.Enable=1;
                    app.IntervalBetweenMeasurementssEditField.Enable=1;
                    if app.ContinuousButton.Value==0
                        app.TotalDurationsEditField.Enable=1;
                        app.NumberofMeasurementsEditField.Enable=1;
                    end
                    soundsc(app.Success,app.SuccessFs);
                else
                    app.StreamingFlag=0;
                    app.StartStreamingButton.Value=0;
                    app.StartStreamingButton.Text='Start Streaming';
                    app.ConnectToSourcemeterButton.Enable=1;
                    app.RunQuickTestButton.Enable=1;
                    app.RunMeasurementButton.Enable=1;

                    app.ButtonGroup.Enable='on';
                    app.ConstantvoltageVEditField.Enable=1;
                    app.ConstantCurrentAEditField.Enable=1;
                    app.ComplianceAEditField_3.Enable=1;
                    app.ComplianceVEditField.Enable=1;
                    app.AutoLightStreamSwitch.Enable=1;
                    app.ContinuousButton.Enable=1;
                    app.FixedNumberButton.Enable=1;
                    app.FixedDurationButton.Enable=1;
                    app.IntervalBetweenMeasurementssEditField.Enable=1;
                    app.SaveButton.Enable=1;
                    if app.ContinuousButton.Value==0
                        app.TotalDurationsEditField.Enable=1;
                        app.NumberofMeasurementsEditField.Enable=1;
                    end
                    
                end
            catch ME
                DebugMessage(app,ME);
                app.ConnectToSourcemeterButton.Enable=1;
                app.RunQuickTestButton.Enable=1;
                app.RunMeasurementButton.Enable=1;
                app.SaveButton.Enable=1;
                
                app.ButtonGroup.Enable='on';
                app.ConstantvoltageVEditField.Enable=1;
                app.ConstantCurrentAEditField.Enable=1;
                app.ComplianceAEditField_3.Enable=1;
                app.ComplianceVEditField.Enable=1;
                app.AutoLightStreamSwitch.Enable=1;
                app.ContinuousButton.Enable=1;
                app.FixedNumberButton.Enable=1;
                app.FixedDurationButton.Enable=1;
                app.IntervalBetweenMeasurementssEditField.Enable=1;
                if app.ContinuousButton.Value==0
                    app.TotalDurationsEditField.Enable=1;
                    app.NumberofMeasurementsEditField.Enable=1;
                end
            end
        end

        % Value changed function: AutoLightStreamSwitch
        function ChangeAutoLightStream(app, event)
            try
                value = app.AutoLightStreamSwitch.Value;
                if strcmp(value,'Fixed Light')
                    app.LatestvalueEditField_2.Visible=0;
                    app.LatestvaluemALabel_2.Visible=0;
                else
                    app.LatestvalueEditField_2.Visible=1;
                     app.LatestvaluemALabel_2.Visible=1;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Button pushed function: SaveButton
        function SaveStreamingData(app, event)
            try
                if exist([app.FolderEditField_2.Value,'\',app.FileNameEditField_2.Value,'.csv'],'file')
                    soundsc(app.Warning,app.WarningFs);
                    Overwrite=uiconfirm(app.UIFigure,'Overwrite existing file?','File already exists','Options',{'Yes','No'},'DefaultOption','No','Icon','Warning');
                    if strcmp(Overwrite,'No')
                        OverwritingFlag=1;
                    elseif strcmp(Overwrite,'Yes')
                        OverwritingFlag=0;
                    end
                else
                    OverwritingFlag=0;
                end
                if ~OverwritingFlag
                    filename=[app.FolderEditField_2.Value,'\',app.FileNameEditField_2.Value,'.csv'];
                    writecell(app.StreamingHeader(1:end-1,:),filename);
                    writecell(app.DescriptionTextArea.Value,filename,'WriteMode','append');
                    writecell(app.StreamingHeader(end,:),filename,'WriteMode','append');
                    writematrix(app.StreamingData,filename,'WriteMode','append');

                    app.SaveButton.Enable=0;
                    app.StreamingDataUnsavedFlag=0;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Selection changed function: ButtonGroup
        function StreamingFixedVar(app, event)
            try
                selectedButton = app.ButtonGroup.SelectedObject;
                if selectedButton == app.ConstantVoltageButton
                    app.ConstantvoltageVEditField.Enable=1;
                    app.ConstantCurrentAEditField.Enable=0;
                    app.ComplianceAEditField_3.Enable=1;
                    app.ComplianceVEditField.Enable=0;
                    if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                        app.LatestvalueAEditField_2.Visible=1;
                        app.LatestvaluemALabel_2.Visible=1;
                    else
                        app.LatestvalueAEditField_2.Visible=0;
                        app.LatestvaluemALabel_2.Visible=0;
                    end
                    app.LatestvalueAEditField.Visible=1;
                    app.LatestvaluemALabel.Visible=1;
                    app.LatestvalueVEditField.Visible=0;
                    app.LatestvalueVLabel.Visible=0;
                    app.LatestvalueVLabel_2.Visible=0;
                    app.LatestvalueVEditField_2.Visible=0;
                elseif selectedButton == app.ConstantCurrentButton
                    app.ConstantvoltageVEditField.Enable=0;
                    app.ConstantCurrentAEditField.Enable=1;
                    app.ComplianceAEditField_3.Enable=0;
                    app.ComplianceVEditField.Enable=1;
                    if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                        app.LatestvalueVEditField_2.Visible=1;
                        app.LatestvalueVLabel_2.Visible=1;
                    else
                        app.LatestvalueVEditField_2.Visible=0;
                        app.LatestvalueVLabel_2.Visible=0;
                    end
                    app.LatestvalueAEditField_2.Visible=0;
                    app.LatestvaluemALabel_2.Visible=0;
                    app.LatestvalueAEditField.Visible=0;
                    app.LatestvaluemALabel.Visible=0;
                    app.LatestvalueVEditField.Visible=1;
                    app.LatestvalueVLabel.Visible=1;
                elseif selectedButton == app.BothcycleButton
                    app.ConstantvoltageVEditField.Enable=1;
                    app.ConstantCurrentAEditField.Enable=1;
                    app.ComplianceAEditField_3.Enable=1;
                    app.ComplianceVEditField.Enable=1;
                    if strcmp(app.AutoLightStreamSwitch.Value,'Auto Dark/Light')
                        app.LatestvalueAEditField_2.Visible=1;
                        app.LatestvalueVEditField_2.Visible=1;
                        app.LatestvalueVLabel_2.Visible=1;
                        app.LatestvaluemALabel_2.Visible=1;
                    else
                        app.LatestvalueAEditField_2.Visible=0;
                        app.LatestvalueVEditField_2.Visible=0;
                        app.LatestvalueVLabel_2.Visible=0;
                        app.LatestvaluemALabel_2.Visible=0;
                    end
                    app.LatestvalueAEditField.Visible=1;
                    app.LatestvaluemALabel.Visible=1;
                    app.LatestvalueVEditField.Visible=1;
                    app.LatestvalueVLabel.Visible=1;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Callback function: BiasValueVEditField, BiasValuesButtonGroup, 
        % ...and 3 other components
        function SelectBiasMode(app, event)
            try
                if app.SingleValueButton.Value
                    app.BiasValueVEditField.Enable=1;
                    app.BiasValueVEditFieldLabel.Enable=1;
                    app.MinBiasVEditField.Enable=0;
                    app.MinBiasVEditFieldLabel.Enable=0;
                    app.MaxBiasVEditField.Enable=0;
                    app.MaxBiasVEditFieldLabel.Enable=0;
                    app.NumberofPointsEditField.Enable=0;
                    app.NumberofPointsEditFieldLabel.Enable=0;
                    app.UIBiasTable.ColumnEditable=false;
                    app.UIBiasTable.Data=app.BiasValueVEditField.Value;
                elseif app.LinearButton.Value
                    app.BiasValueVEditField.Enable=0;
                    app.BiasValueVEditFieldLabel.Enable=0;
                    app.MinBiasVEditField.Enable=1;
                    app.MinBiasVEditFieldLabel.Enable=1;
                    app.MaxBiasVEditField.Enable=1;
                    app.MaxBiasVEditFieldLabel.Enable=1;
                    app.NumberofPointsEditField.Enable=1;
                    app.NumberofPointsEditFieldLabel.Enable=1;
                    app.UIBiasTable.ColumnEditable=false;
                    app.UIBiasTable.Data=linspace(app.MinBiasVEditField.Value,app.MaxBiasVEditField.Value,app.NumberofPointsEditField.Value)';
                elseif app.LogButton.Value
                    app.BiasValueVEditField.Enable=0;
                    app.BiasValueVEditFieldLabel.Enable=0;
                    app.MinBiasVEditField.Enable=1;
                    app.MinBiasVEditFieldLabel.Enable=1;
                    app.MaxBiasVEditField.Enable=1;
                    app.MaxBiasVEditFieldLabel.Enable=1;
                    app.NumberofPointsEditField.Enable=1;
                    app.NumberofPointsEditFieldLabel.Enable=1;
                    app.UIBiasTable.ColumnEditable=false;
                    app.UIBiasTable.Data=logspace(app.MinBiasVEditField.Value,app.MaxBiasVEditField.Value,app.NumberofPointsEditField.Value)';
                elseif app.ListButton.Value
                    app.BiasValueVEditField.Enable=0;
                    app.BiasValueVEditFieldLabel.Enable=0;
                    app.MinBiasVEditField.Enable=0;
                    app.MinBiasVEditFieldLabel.Enable=0;
                    app.MaxBiasVEditField.Enable=0;
                    app.MaxBiasVEditFieldLabel.Enable=0;
                    app.NumberofPointsEditField.Enable=1;
                    app.NumberofPointsEditFieldLabel.Enable=1;
                    app.UIBiasTable.ColumnEditable=true;
                    if length(app.UIBiasTable.Data)>=app.NumberofPointsEditField.Value
                        app.UIBiasTable.Data=app.UIBiasTable.Data(1:app.NumberofPointsEditField.Value);
                    else
                        app.UIBiasTable.Data=[app.UIBiasTable.Data;repmat({''},app.NumberofPointsEditField.Value-length(app.UIBiasTable.Data),1)];
                    end
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: RunButton
        function RunExternalBias(app, event)
            value = app.RunButton.Value;
            Continue='No';
            try
                if value==1
                    if app.ExternalBiasUnsavedFlag
                        soundsc(app.Warning,app.WarningFs);
                        Selection=uiconfirm(app.UIFigure,'Continuing with the run will delete unsaved data, do you want to proceed anyways?','Unsaved Data','Options',{'Yes','No'});
                        if strcmp(Selection,'Yes')
                            Continue='Yes';
                            app.ExternalBiasUnsavedFlag=0;
                        end
                    else
                        Continue='Yes';
                    end
                    if strcmp(Continue,'Yes')
                        app.RunButton.Text='Stop';
    
                        app.SingleValueButton.Enable=0;
                        app.LinearButton.Enable=0;
                        app.LogButton.Enable=0;
                        app.ListButton.Enable=0;
                        app.BiasOffsetVEditField.Editable=0;
                        app.BiasValueVEditField.Editable=0;
                        app.MinBiasVEditField.Editable=0;
                        app.MaxBiasVEditField.Editable=0;
                        app.NumberofPointsEditField.Editable=0;
                        app.VoltageStartEditField_3.Editable=0;
                        app.VoltageStepEditField_3.Editable=0;
                        app.VoltageEndEditField_3.Editable=0;
                        app.ComplianceAEditField_4.Editable=0;
                        app.NumberofMeasurementsEditField_2.Editable=0;
                        app.UIBiasTable.ColumnEditable=false;

                        if iscell(app.UIBiasTable.Data)
                            Bias=cell2mat(app.UIBiasTable.Data);
                        else
                            Bias=app.UIBiasTable.Data;
                        end
                        if strcmp(app.SequenceSwitch.Value,'Random')
                            Bias=Bias(randperm(length(Bias)));
                        end
                        VoltageLine=app.VoltageStartEditField_3.Value:app.VoltageStepEditField_3.Value:app.VoltageEndEditField_3.Value;
                        NumMeasurements=app.NumberofMeasurementsEditField_2.Value;
                        if strcmp(app.LightSwitch.Value,'Auto')
                            NumMeasurements=NumMeasurements*2;
                        end

                        app.ExternalBiasHeader=cell(4,3*NumMeasurements*length(Bias));
                        fprintf(app.device,[':SENS:CURR:PROT:LEV ',num2str(app.ComplianceAEditField_4.Value)]);
                        fprintf(app.device,':SOUR:FUNC VOLT');

                        fprintf(app.device2,[':SENS:CURR:PROT:LEV ',num2str(app.Compliance2ndEditField.Value)]);
                        fprintf(app.device2,':SOUR:FUNC VOLT');
                        data=cell(length(VoltageLine),NumMeasurements*length(Bias));
                        data2=cell(length(VoltageLine),NumMeasurements*length(Bias));
                        ProgressBar=uiprogressdlg(app.UIFigure,"Title",'Measurement in Progress',"Message",['Bias 1/',num2str(length(Bias)),', Run 1/',num2str(NumMeasurements)],'ShowPercentage',1,'Value',0,'Cancelable',1);
                        for ii=1:length(Bias)
                            fprintf(app.device2,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(Bias(ii)+app.BiasOffsetVEditField.Value)]);
                            for jj=1:NumMeasurements
                                ProgressBar.Message=['Bias ',num2str(ii),'/',num2str(length(Bias)),', Run ',num2str(jj),'/',num2str(NumMeasurements)];
                                Index=3*NumMeasurements*(ii-1)+3*(jj-1);
                                app.ExternalBiasHeader(2,Index+1:Index+3)=repmat({date},1,3);
                                app.ExternalBiasHeader(3,Index+1:Index+3)=repmat({datestr(now,'HH:MM:SS')},1,3);
                                app.ExternalBiasHeader(4,Index+1:Index+3)=repmat({Bias(ii)},1,3);
                                if strcmp(app.LightSwitch.Value,'Auto')
                                    if jj==1
                                        Light='Dark';
                                        SwitchLight(app,0);
                                    elseif jj==NumMeasurements/2+1
                                        Light='Light';
                                        SwitchLight(app,1);
                                    end
                                    app.ExternalBiasHeader(5,Index+1:Index+3)=repmat({Light},1,3);
                                end
                                for kk=1:length(VoltageLine)
                                    ProgressBar.Value=min(max((length(VoltageLine)*NumMeasurements*(ii-1)+length(VoltageLine)*(jj-1)+kk)/(length(Bias)*NumMeasurements*length(VoltageLine)),0),1);
                                    fprintf(app.device,['SOUR:VOLT:LEV:IMM:AMPL ',num2str(VoltageLine(kk))]);
                                    pause(app.PauseBetweenMeasurementssEditField_2.Value)
                                    data{kk,NumMeasurements*(ii-1)+jj}=query(app.device,':MEAS:CURR?');
                                    data2{kk,NumMeasurements*(ii-1)+jj}=query(app.device2,':MEAS:CURR?');
                                    if ProgressBar.CancelRequested
                                        close(ProgressBar);

                                        app.RunButton.Text='Run';
                                        app.RunButton.Value=0;
                        
                                        app.SingleValueButton.Enable=1;
                                        app.LinearButton.Enable=1;
                                        app.LogButton.Enable=1;
                                        app.ListButton.Enable=1;
                                        app.BiasOffsetVEditField.Editable=1;
                                        app.BiasValueVEditField.Editable=1;
                                        app.MinBiasVEditField.Editable=1;
                                        app.MaxBiasVEditField.Editable=1;
                                        app.NumberofPointsEditField.Editable=1;
                                        app.VoltageStartEditField_3.Editable=1;
                                        app.VoltageStepEditField_3.Editable=1;
                                        app.VoltageEndEditField_3.Editable=1;
                                        app.ComplianceAEditField_4.Editable=1;
                                        app.NumberofMeasurementsEditField_2.Editable=1;
                                        if app.ListButton.Value
                                            app.UIBiasTable.ColumnEditable=true;
                                        end
                                        if strcmp(app.LightSwitch.Value,'Auto')
                                            SwitchLight(app,0);
                                        end
                                        
                                        return
                                    end
                                end
                            end
                            soundsc(app.Success,app.SuccessFs);
                        end
                        soundsc(app.Success,1.5*app.SuccessFs);
                        fprintf(app.device,':OUTP OFF');
                        fprintf(app.device2,':OUTP OFF');
                        if strcmp(app.LightSwitch.Value,'Auto')
                            SwitchLight(app,0);
                        end

                        app.ExternalBiasData=NaN(length(VoltageLine),3*NumMeasurements*length(Bias));
                        Row=size(app.ExternalBiasHeader,1)+1;
                        for jj=1:NumMeasurements*length(Bias)
                            app.ExternalBiasHeader(Row,3*(jj-1)+1:3*(jj-1)+3)={'Voltage (V)','Current (A)','2nd Current (A)'};
                            for ii=1:length(VoltageLine)
                                app.ExternalBiasData(ii,3*(jj-1)+1)=str2double(data{ii,jj}(1:9))*10^(str2double(data{ii,jj}(11:13)));
                                app.ExternalBiasData(ii,3*(jj-1)+2)=str2double(data{ii,jj}(15:23))*10^(str2double(data{ii,jj}(25:27)));
                                app.ExternalBiasData(ii,3*(jj-1)+3)=str2double(data2{ii,jj}(1:9))*10^(str2double(data2{ii,jj}(11:13)));
                            end
                        end
                        app.SaveButton_2.Enable=1;
                        app.ExternalBiasUnsavedFlag=1;

                        plot(app.UIAxes,app.ExternalBiasData(:,1:3:end),app.ExternalBiasData(:,2:3:end).*1000);
                        plot(app.UIAxes_2,app.ExternalBiasData(:,1:3:end),app.ExternalBiasData(:,3:3:end).*1000);
                        app.RunButton.Text='Run';
                        app.RunButton.Value=0;

                        app.SingleValueButton.Enable=1;
                        app.LinearButton.Enable=1;
                        app.LogButton.Enable=1;
                        app.ListButton.Enable=1;
                        app.BiasOffsetVEditField.Editable=1;
                        app.BiasValueVEditField.Editable=1;
                        app.MinBiasVEditField.Editable=1;
                        app.MaxBiasVEditField.Editable=1;
                        app.NumberofPointsEditField.Editable=1;
                        app.VoltageStartEditField_3.Editable=1;
                        app.VoltageStepEditField_3.Editable=1;
                        app.VoltageEndEditField_3.Editable=1;
                        app.ComplianceAEditField_4.Editable=1;
                        app.NumberofMeasurementsEditField_2.Editable=1;
                        if app.ListButton.Value
                            app.UIBiasTable.ColumnEditable=true;
                        end
                    end
                elseif value==0
                    app.RunButton.Text='Run';

                    app.SingleValueButton.Enable=1;
                    app.LinearButton.Enable=1;
                    app.LogButton.Enable=1;
                    app.ListButton.Enable=1;
                    app.BiasOffsetVEditField.Editable=1;
                    app.BiasValueVEditField.Editable=1;
                    app.MinBiasVEditField.Editable=1;
                    app.MaxBiasVEditField.Editable=1;
                    app.NumberofPointsEditField.Editable=1;
                    app.VoltageStartEditField_3.Editable=1;
                    app.VoltageStepEditField_3.Editable=1;
                    app.VoltageEndEditField_3.Editable=1;
                    app.ComplianceAEditField_4.Editable=1;
                    app.NumberofMeasurementsEditField_2.Editable=1;
                    if app.ListButton.Value
                        app.UIBiasTable.ColumnEditable=true;
                    end
                end
            catch ME
                DebugMessage(app,ME);

                app.RunButton.Text='Run';
                app.RunButton.Value=0;

                app.SingleValueButton.Enable=1;
                app.LinearButton.Enable=1;
                app.LogButton.Enable=1;
                app.ListButton.Enable=1;
                app.BiasOffsetVEditField.Editable=1;
                app.BiasValueVEditField.Editable=1;
                app.MinBiasVEditField.Editable=1;
                app.MaxBiasVEditField.Editable=1;
                app.NumberofPointsEditField.Editable=1;
                app.VoltageStartEditField_3.Editable=1;
                app.VoltageStepEditField_3.Editable=1;
                app.VoltageEndEditField_3.Editable=1;
                app.ComplianceAEditField_4.Editable=1;
                app.NumberofMeasurementsEditField_2.Editable=1;
                if app.ListButton.Value
                    app.UIBiasTable.ColumnEditable=true;
                end
                if exist('ProgressBar','var')
                    close(ProgressBar);
                end
            end
        end

        % Button pushed function: SaveButton_2
        function SaveExternalBias(app, event)
            try
                filename=[app.FolderEditField_3.Value,'\',app.FileNameEditField_3.Value,'.csv'];
                if exist(filename,'file')
                    soundsc(app.Warning,app.WarningFs);
                    Overwrite=uiconfirm(app.UIFigure,'Overwrite existing file?','File already exists','Options',{'Yes','No'},'DefaultOption','No','Icon','Warning');
                    if strcmp(Overwrite,'No')
                        OverwritingFlag=1;
                    elseif strcmp(Overwrite,'Yes')
                        OverwritingFlag=0;
                    end
                else
                    OverwritingFlag=0;
                end
                if ~OverwritingFlag
                    app.ExternalBiasHeader{1,1}=app.DescriptionEditField.Value{1};
                    writecell(app.ExternalBiasHeader,filename);
                    writematrix(app.ExternalBiasData,filename,'WriteMode','append');

                    app.ExternalBiasUnsavedFlag=0;
                    app.SaveButton_2.Enable=0;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: Connectto2ndSMUButton
        function Connect2ndSMU(app, event)
            try
                app.StatusEditField_2.Value='Connecting...';
                app.StatusEditField_2.FontColor=[0,0,0];
                pause(0.001);
                Ports=serialportlist;
                try
                    test=app.Arduino.Port;
                catch
                    test='0';
                end
                for ii=1:length(Ports)
                    if  ~strcmp(Ports{ii},test) && ~strcmp(Ports{ii},app.device.Port)
                        app.device2=serial(Ports{ii},'BaudRate',9600);
                        fopen(app.device2);
                        IDN=query(app.device2,'*IDN?');
                        if strcmp(IDN(1:end-1),'KEITHLEY INSTRUMENTS INC.,MODEL 2401,1361980,A01 Aug 25 2011 12:57:43/A02  /S/K')
                            app.device2.Tag='SMU2';
                            fprintf(app.device2,'*RST');
                            fprintf(app.device2,':OUTP OFF');
                            app.StatusEditField_2.Value='Connected';
                            app.Connectto2ndSMUButton.Text={'Disconnect from';'Sourcemeter'};
                            app.StatusEditField_2.FontColor=[0,1,0];
                            app.RunButton.Enable=1;
                            break
                        else
                            fclose(app.device2);
                        end
                    end
                    
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: SourceSwitch
        function LightSourceChanged(app, event)
            try
                value = app.SourceSwitch.Value;
                app.IlluminationSwitch.Value='Dark';
                if strcmp(value,'Solar Simulator')
                    write(app.Arduino,'2','char');
                    app.LightControlSwitch.Enable=1;
                elseif strcmp(value,'LED')
                    write(app.Arduino,'0','char');
                    app.LightControlSwitch.Enable=0;
                    app.LightControlSwitch.Value='Auto';
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: BeepingSwitch
        function SwitchBeeping(app, event)
            try
                value = app.BeepingSwitch.Value;
                if strcmp(value,'On')
                    app.BeepingLamp.Enable=1;
                else
                    app.BeepingLamp.Enable=0;
                end
            catch ME
                DebugMessage(app,ME);
            end
        end

        % Value changed function: MaxSignalmAEditField, 
        % ...and 1 other component
        function ChangeBeepLimits(app, event)
            try
                if app.MinSignalmAEditField.Value>=app.MaxSignalmAEditField.Value
                    uialert(app.UIFigure,'Warning: The beep limits entered are not valid. Make sure that the minimum value is lesser than the maximum valie. Continuing will result in no useful auditive signal being produced.','Invalid Values','Icon','warning','modal',false);
                    soundsc(app.Error,app.ErrorFs);
                end
            catch ME
                DebugMessage(app,ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Close, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NewMeasurementTab = uitab(app.TabGroup);
            app.NewMeasurementTab.Title = 'New Measurement';
            app.IVcurveAxes = uiaxes(app.NewMeasurementTab);
            title(app.IVcurveAxes, 'Last Run Preview')
            xlabel(app.IVcurveAxes, 'Voltage (V)')
            ylabel(app.IVcurveAxes, 'Current (A)')
            app.MeasurementConfigurationPanel = uipanel(app.NewMeasurementTab);
            app.MeasurementConfigurationPanel.Title = 'Measurement Configuration';
            app.VoltageEndEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.VoltageEndEditFieldLabel.Text = 'Voltage End';
            app.VoltageEndEditField = uieditfield(app.MeasurementConfigurationPanel, 'numeric');
            app.VoltageEndEditField.Limits = [-210 210];
            app.VoltageEndEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageEndChanged, true);
            app.VoltageEndEditField.Value = 1.5;
            app.VoltageStartEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.VoltageStartEditFieldLabel.Text = 'Voltage Start';
            app.VoltageStartEditField = uieditfield(app.MeasurementConfigurationPanel, 'numeric');
            app.VoltageStartEditField.Limits = [-210 210];
            app.VoltageStartEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageStartChanged, true);
            app.VoltageStartEditField.Value = -1.5;
            app.VoltageStepEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.VoltageStepEditFieldLabel.Text = 'Voltage Step';
            app.VoltageStepEditField = uieditfield(app.MeasurementConfigurationPanel, 'numeric');
            app.VoltageStepEditField.Limits = [-3 3];
            app.VoltageStepEditField.Value = 0.01;
            app.ComplianceAEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.ComplianceAEditFieldLabel.Text = 'Compliance (A)';
            app.ComplianceAEditField = uieditfield(app.MeasurementConfigurationPanel, 'numeric');
            app.ComplianceAEditField.Limits = [-1.05 1.05];
            app.ComplianceAEditField.ValueChangedFcn = createCallbackFcn(app, @ComplianceChanged, true);
            app.ComplianceAEditField.Value = 0.02;
            app.NumberOfMeasurementsEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.NumberOfMeasurementsEditFieldLabel.Text = 'Number Of Measurements';
            app.NumberOfMeasurementsEditField = uieditfield(app.MeasurementConfigurationPanel, 'numeric');
            app.NumberOfMeasurementsEditField.Limits = [1 Inf];
            app.NumberOfMeasurementsEditField.Value = 3;
            app.PauseBetweenMeasurementssEditFieldLabel = uilabel(app.MeasurementConfigurationPanel);
            app.PauseBetweenMeasurementssEditFieldLabel.Text = 'Pause Between Measurements (s)';
            app.PauseBetweenMeasurementssEditField = uieditfield(app.MeasurementConfigurationPanel, 'numeric');
            app.PauseBetweenMeasurementssEditField.Limits = [0 Inf];
            app.PauseBetweenMeasurementssEditField.Value = 0.01;
            app.QuickTestConfigurationPanel = uipanel(app.NewMeasurementTab);
            app.QuickTestConfigurationPanel.Title = 'Quick Test Configuration';
            app.VoltageEndEditField_2Label = uilabel(app.QuickTestConfigurationPanel);
            app.VoltageEndEditField_2Label.Text = 'Voltage End';
            app.VoltageEndEditField_2 = uieditfield(app.QuickTestConfigurationPanel, 'numeric');
            app.VoltageEndEditField_2.Limits = [-210 210];
            app.VoltageEndEditField_2.Value = 1.5;
            app.VoltageStartEditField_2Label = uilabel(app.QuickTestConfigurationPanel);
            app.VoltageStartEditField_2Label.Text = 'Voltage Start';
            app.VoltageStartEditField_2 = uieditfield(app.QuickTestConfigurationPanel, 'numeric');
            app.VoltageStartEditField_2.Limits = [-210 210];
            app.VoltageStartEditField_2.Value = -1.5;
            app.VoltageStepEditField_2Label = uilabel(app.QuickTestConfigurationPanel);
            app.VoltageStepEditField_2Label.Text = 'Voltage Step';
            app.VoltageStepEditField_2 = uieditfield(app.QuickTestConfigurationPanel, 'numeric');
            app.VoltageStepEditField_2.Limits = [-210 210];
            app.VoltageStepEditField_2.Value = 0.2;
            app.ComplianceAEditField_2Label = uilabel(app.QuickTestConfigurationPanel);
            app.ComplianceAEditField_2Label.Text = 'Compliance (A)';
            app.ComplianceAEditField_2 = uieditfield(app.QuickTestConfigurationPanel, 'numeric');
            app.ComplianceAEditField_2.Limits = [-1.05 1.05];
            app.ComplianceAEditField_2.Value = 0.02;
            app.PauseBetweenMeasurementssEditField_2Label = uilabel(app.QuickTestConfigurationPanel);
            app.PauseBetweenMeasurementssEditField_2Label.Text = 'Pause Between Measurements (s)';
            app.PauseBetweenMeasurementssEditField_2 = uieditfield(app.QuickTestConfigurationPanel, 'numeric');
            app.PauseBetweenMeasurementssEditField_2.Limits = [0 Inf];
            app.PauseBetweenMeasurementssEditField_2.Value = 0.01;
            app.RunMeasurementButton = uibutton(app.NewMeasurementTab, 'push');
            app.RunMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @RunMeasurement, true);
            app.RunMeasurementButton.Enable = 'off';
            app.RunMeasurementButton.Text = 'Run Measurement';
            app.RunQuickTestButton = uibutton(app.NewMeasurementTab, 'push');
            app.RunQuickTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTest, true);
            app.RunQuickTestButton.Enable = 'off';
            app.RunQuickTestButton.Text = 'Run Quick Test';
            app.SampleInformationPanel = uipanel(app.NewMeasurementTab);
            app.SampleInformationPanel.Title = 'Sample Information';
            app.WaferNameEditFieldLabel = uilabel(app.SampleInformationPanel);
            app.WaferNameEditFieldLabel.Text = 'Wafer Name';
            app.WaferNameEditField = uieditfield(app.SampleInformationPanel, 'text');
            app.WaferNameEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeIdField, true);
            app.ChipEditFieldLabel = uilabel(app.SampleInformationPanel);
            app.ChipEditFieldLabel.Text = 'Chip';
            app.ChipEditField = uieditfield(app.SampleInformationPanel, 'text');
            app.ChipEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeIdField, true);
            app.StepOfTheProcessEditFieldLabel = uilabel(app.SampleInformationPanel);
            app.StepOfTheProcessEditFieldLabel.Text = 'Step Of The Process';
            app.StepOfTheProcessEditField = uieditfield(app.SampleInformationPanel, 'text');
            app.StepOfTheProcessEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeIdField, true);
            app.CommentsTextAreaLabel = uilabel(app.SampleInformationPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.SampleInformationPanel);
            app.DiscardLastRunButton = uibutton(app.SampleInformationPanel, 'push');
            app.DiscardLastRunButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardLastRun, true);
            app.DiscardLastRunButton.Enable = 'off';
            app.DiscardLastRunButton.Text = 'Discard Last Run';
            app.StoreLastRunButton = uibutton(app.SampleInformationPanel, 'push');
            app.StoreLastRunButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLastRun, true);
            app.StoreLastRunButton.Enable = 'off';
            app.StoreLastRunButton.Text = 'Store Last Run';
            app.IlluminationSwitch = uiswitch(app.SampleInformationPanel, 'toggle');
            app.IlluminationSwitch.Items = {'Dark', 'Light'};
            app.IlluminationSwitch.ValueChangedFcn = createCallbackFcn(app, @IlluminationChanged, true);
            app.IlluminationSwitch.Value = 'Dark';
            app.Lamp = uilamp(app.SampleInformationPanel);
            app.Lamp.Enable = 'off';
            app.ParametersTable = uitable(app.SampleInformationPanel);
            app.ParametersTable.ColumnName = {''; 'Parameter'; 'Value'};
            app.ParametersTable.ColumnEditable = [true false true];
            app.ParametersTable.CellEditCallback = createCallbackFcn(app, @EditParametersTable, true);
            app.ParametesTextAreaLabel = uilabel(app.SampleInformationPanel);
            app.ParametesTextAreaLabel.Text = 'Additional Parameters';
            app.AddParameterButton = uibutton(app.SampleInformationPanel, 'push');
            app.AddParameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddParameter, true);
            app.AddParameterButton.Enable = 'off';
            app.AddParameterButton.Text = 'Add Parameter';
            app.DeleteSelectedParametersButton = uibutton(app.SampleInformationPanel, 'push');
            app.DeleteSelectedParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteParameters, true);
            app.DeleteSelectedParametersButton.Enable = 'off';
            app.DeleteSelectedParametersButton.Text = {'Delete Selected'; 'Parameters'};
            app.ParameterEditField = uieditfield(app.SampleInformationPanel, 'text');
            app.ParameterEditField.ValueChangedFcn = createCallbackFcn(app, @ParameterEntered, true);
            app.ParameterEditField.ValueChangingFcn = createCallbackFcn(app, @EditingParameter, true);
            app.ConnectToSourcemeterButton = uibutton(app.NewMeasurementTab, 'push');
            app.ConnectToSourcemeterButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleConnect, true);
            app.ConnectToSourcemeterButton.Text = {'Connect To '; 'Sourcemeter'};
            app.StatusEditFieldLabel = uilabel(app.NewMeasurementTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.NewMeasurementTab, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Disconnected';
            app.LightControlPanel = uipanel(app.NewMeasurementTab);
            app.LightControlPanel.Title = 'Light Control';
            app.LightControlSwitch_2Label = uilabel(app.LightControlPanel);
            app.LightControlSwitch_2Label.Enable = 'off';
            app.LightControlSwitch_2Label.Text = 'Light Control';
            app.LightControlSwitch = uiswitch(app.LightControlPanel, 'slider');
            app.LightControlSwitch.Items = {'Manual', 'Auto'};
            app.LightControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleLightControl, true);
            app.LightControlSwitch.Value = 'Manual';
            app.AutoDarkLightSwitchLabel = uilabel(app.LightControlPanel);
            app.AutoDarkLightSwitchLabel.Enable = 'off';
            app.AutoDarkLightSwitchLabel.Text = 'Auto Dark/Light';
            app.AutoDarkLightSwitch = uiswitch(app.LightControlPanel, 'toggle');
            app.AutoDarkLightSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAutoDarkLight, true);
            app.AutoDarkLightSwitch.Enable = 'off';
            app.Lamp_2 = uilamp(app.LightControlPanel);
            app.Lamp_2.Enable = 'off';
            app.SourceSwitchLabel = uilabel(app.LightControlPanel);
            app.SourceSwitchLabel.Enable = 'off';
            app.SourceSwitchLabel.Text = 'Source:';
            app.SourceSwitch = uiswitch(app.LightControlPanel, 'toggle');
            app.SourceSwitch.Items = {'Solar Simulator', 'LED'};
            app.SourceSwitch.ValueChangedFcn = createCallbackFcn(app, @LightSourceChanged, true);
            app.SourceSwitch.Enable = 'off';
            app.SourceSwitch.Value = 'Solar Simulator';
            app.DataManagementTab = uitab(app.TabGroup);
            app.DataManagementTab.Title = 'Data Management';
            app.DataTable = uitable(app.DataManagementTab);
            app.DataTable.ColumnName = {''};
            app.HeaderTable = uitable(app.DataManagementTab);
            app.HeaderTable.RowName = {'Wafer'; 'Chip'; 'Step'; 'Illumination'; 'Comments'; 'Plot'; 'I sc (mA)'; 'V oc (V)'};
            app.HeaderTable.ColumnEditable = true;
            app.HeaderTable.CellEditCallback = createCallbackFcn(app, @ChangeIdTable, true);
            app.HeaderTable.CellSelectionCallback = createCallbackFcn(app, @SelectIdTable, true);
            app.UpdatePlotButton = uibutton(app.DataManagementTab, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.SaveDataButton = uibutton(app.DataManagementTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveDataButton.Text = 'Save Data';
            app.FileNameEditFieldLabel = uilabel(app.DataManagementTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.DataManagementTab, 'text');
            app.ChooseFolderButton = uibutton(app.DataManagementTab, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @GetFolder, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.FolderEditFieldLabel = uilabel(app.DataManagementTab);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.DataManagementTab, 'text');
            app.DeleteUnnamedColumnsButton = uibutton(app.DataManagementTab, 'push');
            app.DeleteUnnamedColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteColumns, true);
            app.DeleteUnnamedColumnsButton.Text = 'Delete Unnamed Columns';
            app.AnalyzeDataCheckBox = uicheckbox(app.DataManagementTab);
            app.AnalyzeDataCheckBox.Text = '';
            app.AnalyzedataandsaveresultsinthesamefolderLabel = uilabel(app.DataManagementTab);
            app.AnalyzedataandsaveresultsinthesamefolderLabel.Text = 'Analyze data and save results in the same folder';
            app.ClearPlotSelectionButton = uibutton(app.DataManagementTab, 'push');
            app.ClearPlotSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotSelection, true);
            app.ClearPlotSelectionButton.Text = {'Clear'; 'Plot'; 'Selection'};
            app.ClearAllDataButton = uibutton(app.DataManagementTab, 'push');
            app.ClearAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllData, true);
            app.ClearAllDataButton.Text = 'Clear All Data';
            app.ParametersStoreTable = uitable(app.DataManagementTab);
            app.ParametersStoreTable.RowName = {};
            app.ParametersStoreTable.ColumnEditable = true;
            app.ParametersStoreTable.CellEditCallback = createCallbackFcn(app, @ParametersStoreTableEdited, true);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.SummaryAxes = uiaxes(app.PlotTab);
            xlabel(app.SummaryAxes, 'Voltage (V)')
            ylabel(app.SummaryAxes, 'Current (A)')
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PhotocurrentsTable = uitable(app.ResultsTab);
            app.PhotocurrentsTable.ColumnName = {''};
            app.PhotocurrentsTable.RowName = {'Wafer'; 'Chip'; 'Step'; 'Mean PC (A)'; ' std PC (A)'};
            app.RefreshCalculationsButton = uibutton(app.ResultsTab, 'push');
            app.RefreshCalculationsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCalculations, true);
            app.RefreshCalculationsButton.Text = 'Refresh Calculations';
            app.PhotocurrentcomparisonsPanel = uipanel(app.ResultsTab);
            app.PhotocurrentcomparisonsPanel.Title = 'Photocurrent comparisons';
            app.SelectWaferLabel = uilabel(app.PhotocurrentcomparisonsPanel);
            app.SelectWaferLabel.Text = 'Select Wafer';
            app.WaferDropDown = uidropdown(app.PhotocurrentcomparisonsPanel);
            app.WaferDropDown.Items = {};
            app.WaferDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectWafer, true);
            app.WaferDropDown.Value = {};
            app.SelectChipLabel = uilabel(app.PhotocurrentcomparisonsPanel);
            app.SelectChipLabel.Text = 'Select Chip';
            app.ChipDropDown = uidropdown(app.PhotocurrentcomparisonsPanel);
            app.ChipDropDown.Items = {};
            app.ChipDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectChip, true);
            app.ChipDropDown.Value = {};
            app.PCdiffTable = uitable(app.PhotocurrentcomparisonsPanel);
            app.PCdiffTable.ColumnName = {''};
            app.PCdiffTable.RowName = {};
            app.PCratioTable = uitable(app.PhotocurrentcomparisonsPanel);
            app.PCratioTable.ColumnName = {''};
            app.PCratioTable.RowName = {};
            app.DifferenceAcolumnrowLabel = uilabel(app.PhotocurrentcomparisonsPanel);
            app.DifferenceAcolumnrowLabel.Text = 'Difference (A) (column - row)';
            app.Ratiocolumnrow1Label = uilabel(app.PhotocurrentcomparisonsPanel);
            app.Ratiocolumnrow1Label.Text = 'Ratio (column/row - 1)';
            app.DataStreamingTab = uitab(app.TabGroup);
            app.DataStreamingTab.Title = 'Data Streaming';
            app.UIStreamAxes = uiaxes(app.DataStreamingTab);
            xlabel(app.UIStreamAxes, 'Time (s)')
            ylabel(app.UIStreamAxes, 'Current (mA)')
            app.LatestvalueVLabel = uilabel(app.DataStreamingTab);
            app.LatestvalueVLabel.Visible = 'off';
            app.LatestvalueVLabel.Text = 'Latest value (V)';
            app.LatestvaluemALabel = uilabel(app.DataStreamingTab);
            app.LatestvaluemALabel.Text = 'Latest value (mA)';
            app.LatestvaluemALabel_2 = uilabel(app.DataStreamingTab);
            app.LatestvaluemALabel_2.Visible = 'off';
            app.LatestvaluemALabel_2.Text = 'Latest value (mA)';
            app.LatestvalueVLabel_2 = uilabel(app.DataStreamingTab);
            app.LatestvalueVLabel_2.Visible = 'off';
            app.LatestvalueVLabel_2.Text = 'Latest value (V)';
            app.Panel = uipanel(app.DataStreamingTab);
            app.ChooseFolderButton_2 = uibutton(app.Panel, 'push');
            app.ChooseFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetFolder, true);
            app.ChooseFolderButton_2.Text = 'Choose Folder';
            app.FolderEditField_2Label = uilabel(app.Panel);
            app.FolderEditField_2Label.Text = 'Folder';
            app.FolderEditField_2 = uieditfield(app.Panel, 'text');
            app.FileNameEditField_2Label = uilabel(app.Panel);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.Panel, 'text');
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStreamingData, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.DescriptionTextAreaLabel = uilabel(app.Panel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.Panel);
            app.Panel_2 = uipanel(app.DataStreamingTab);
            app.MeasurementModeButtonGroup = uibuttongroup(app.Panel_2);
            app.MeasurementModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectMeasurementMode, true);
            app.MeasurementModeButtonGroup.Title = 'Measurement Mode';
            app.ContinuousButton = uitogglebutton(app.MeasurementModeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.FixedNumberButton = uitogglebutton(app.MeasurementModeButtonGroup);
            app.FixedNumberButton.Text = 'Fixed Number';
            app.FixedDurationButton = uitogglebutton(app.MeasurementModeButtonGroup);
            app.FixedDurationButton.Text = 'Fixed Duration';
            app.AutoLightStreamSwitch = uiswitch(app.Panel_2, 'toggle');
            app.AutoLightStreamSwitch.Items = {'Fixed Light', 'Auto Dark/Light'};
            app.AutoLightStreamSwitch.ValueChangedFcn = createCallbackFcn(app, @ChangeAutoLightStream, true);
            app.AutoLightStreamSwitch.Enable = 'off';
            app.AutoLightStreamSwitch.Value = 'Fixed Light';
            app.MeasurementFrequencysLabel = uilabel(app.Panel_2);
            app.MeasurementFrequencysLabel.Text = 'Interval Between Measurements (s):';
            app.IntervalBetweenMeasurementssEditField = uieditfield(app.Panel_2, 'numeric');
            app.IntervalBetweenMeasurementssEditField.Limits = [0.25 Inf];
            app.IntervalBetweenMeasurementssEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeStreamParameters, true);
            app.IntervalBetweenMeasurementssEditField.Value = 1;
            app.NumberofMeasurementsLabel = uilabel(app.Panel_2);
            app.NumberofMeasurementsLabel.Text = 'Number of Measurements:';
            app.NumberofMeasurementsEditField = uieditfield(app.Panel_2, 'numeric');
            app.NumberofMeasurementsEditField.Limits = [1 Inf];
            app.NumberofMeasurementsEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeStreamParameters, true);
            app.NumberofMeasurementsEditField.Enable = 'off';
            app.NumberofMeasurementsEditField.Value = Inf;
            app.ConstantvoltageVEditField = uieditfield(app.Panel_2, 'numeric');
            app.ConstantVoltageVLabel = uilabel(app.Panel_2);
            app.ConstantVoltageVLabel.Text = 'Constant Voltage (V):';
            app.ComplianceAEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.ComplianceAEditField_3.Value = 0.02;
            app.ComplianceALabel = uilabel(app.Panel_2);
            app.ComplianceALabel.Text = 'Compliance (A):';
            app.TotalDurationsEditFieldLabel = uilabel(app.Panel_2);
            app.TotalDurationsEditFieldLabel.Text = 'Total Duration (s):';
            app.TotalDurationsEditField = uieditfield(app.Panel_2, 'numeric');
            app.TotalDurationsEditField.Limits = [0 Inf];
            app.TotalDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeStreamParameters, true);
            app.TotalDurationsEditField.Enable = 'off';
            app.TotalDurationsEditField.Value = Inf;
            app.StartStreamingButton = uibutton(app.Panel_2, 'state');
            app.StartStreamingButton.ValueChangedFcn = createCallbackFcn(app, @StartStreaming, true);
            app.StartStreamingButton.Enable = 'off';
            app.StartStreamingButton.Text = 'Start Streaming';
            app.ConstantCurrentAEditField = uieditfield(app.Panel_2, 'numeric');
            app.ConstantCurrentAEditField.Enable = 'off';
            app.ConstantVoltageVLabel_2 = uilabel(app.Panel_2);
            app.ConstantVoltageVLabel_2.Enable = 'off';
            app.ConstantVoltageVLabel_2.Text = 'Constant Current (A):';
            app.ComplianceVEditField = uieditfield(app.Panel_2, 'numeric');
            app.ComplianceVEditField.Enable = 'off';
            app.ComplianceVEditField.Value = 1;
            app.ComplianceVEditFieldLabel = uilabel(app.Panel_2);
            app.ComplianceVEditFieldLabel.Enable = 'off';
            app.ComplianceVEditFieldLabel.Text = 'Compliance (V):';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StreamingFixedVar, true);
            app.ConstantVoltageButton = uiradiobutton(app.ButtonGroup);
            app.ConstantVoltageButton.Text = 'Constant Voltage';
            app.ConstantVoltageButton.Value = true;
            app.ConstantCurrentButton = uiradiobutton(app.ButtonGroup);
            app.ConstantCurrentButton.Text = 'Constant Current';
            app.BothcycleButton = uiradiobutton(app.ButtonGroup);
            app.BothcycleButton.Text = 'Both (cycle)';
            app.LatestvalueAEditField = uieditfield(app.DataStreamingTab, 'numeric');
            app.LatestvalueAEditField.Editable = 'off';
            app.LatestvalueVEditField = uieditfield(app.DataStreamingTab, 'numeric');
            app.LatestvalueVEditField.Editable = 'off';
            app.LatestvalueVEditField.Visible = 'off';
            app.LatestvalueAEditField_2 = uieditfield(app.DataStreamingTab, 'numeric');
            app.LatestvalueAEditField_2.Editable = 'off';
            app.LatestvalueAEditField_2.Visible = 'off';
            app.LatestvalueVEditField_2 = uieditfield(app.DataStreamingTab, 'numeric');
            app.LatestvalueVEditField_2.Editable = 'off';
            app.LatestvalueVEditField_2.Visible = 'off';
            app.BeepingControlPanel = uipanel(app.DataStreamingTab);
            app.BeepingControlPanel.Title = 'Beeping Control';
            app.BeepingSwitchLabel = uilabel(app.BeepingControlPanel);
            app.BeepingSwitchLabel.Text = 'Beeping';
            app.BeepingSwitch = uiswitch(app.BeepingControlPanel, 'slider');
            app.BeepingSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchBeeping, true);
            app.BeepingSwitch.Value = 'On';
            app.BeepingLamp = uilamp(app.BeepingControlPanel);
            app.MinSignalmAEditFieldLabel = uilabel(app.BeepingControlPanel);
            app.MinSignalmAEditFieldLabel.Text = 'Min Signal (mA)';
            app.MinSignalmAEditField = uieditfield(app.BeepingControlPanel, 'numeric');
            app.MinSignalmAEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeBeepLimits, true);
            app.MinSignalmAEditField.Value = -1;
            app.MaxSignalmAEditFieldLabel = uilabel(app.BeepingControlPanel);
            app.MaxSignalmAEditFieldLabel.Text = 'Max Signal (mA)';
            app.MaxSignalmAEditField = uieditfield(app.BeepingControlPanel, 'numeric');
            app.MaxSignalmAEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeBeepLimits, true);
            app.PitchHzSliderLabel = uilabel(app.DataStreamingTab);
            app.PitchHzSliderLabel.Text = 'Pitch (Hz)';
            app.PitchHzSlider = uislider(app.DataStreamingTab);
            app.PitchHzSlider.Limits = [220 880];
            app.PitchHzSlider.Value = 440;
            app.ExternalBiasTab = uitab(app.TabGroup);
            app.ExternalBiasTab.Title = 'External Bias';
            app.UIAxes = uiaxes(app.ExternalBiasTab);
            title(app.UIAxes, 'IV')
            xlabel(app.UIAxes, 'Voltage (V)')
            ylabel(app.UIAxes, 'Current (mA)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ExternalBiasTab);
            title(app.UIAxes_2, 'External Current')
            xlabel(app.UIAxes_2, 'Voltage (V)')
            ylabel(app.UIAxes_2, 'Current (mA)')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_3 = uipanel(app.ExternalBiasTab);
            app.BiasValuesButtonGroup = uibuttongroup(app.Panel_3);
            app.BiasValuesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectBiasMode, true);
            app.BiasValuesButtonGroup.Title = 'Bias Values';
            app.SingleValueButton = uiradiobutton(app.BiasValuesButtonGroup);
            app.SingleValueButton.Text = 'Single Value';
            app.SingleValueButton.Value = true;
            app.LinearButton = uiradiobutton(app.BiasValuesButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LogButton = uiradiobutton(app.BiasValuesButtonGroup);
            app.LogButton.Text = 'Log';
            app.ListButton = uiradiobutton(app.BiasValuesButtonGroup);
            app.ListButton.Text = 'List';
            app.UIBiasTable = uitable(app.Panel_3);
            app.UIBiasTable.ColumnName = {'Bias Value (V)'};
            app.UIBiasTable.ColumnSortable = true;
            app.BiasOffsetVEditFieldLabel = uilabel(app.Panel_3);
            app.BiasOffsetVEditFieldLabel.Text = 'Bias Offset (V)';
            app.BiasOffsetVEditField = uieditfield(app.Panel_3, 'numeric');
            app.MinBiasVEditField = uieditfield(app.Panel_3, 'numeric');
            app.MinBiasVEditField.ValueChangedFcn = createCallbackFcn(app, @SelectBiasMode, true);
            app.MinBiasVEditField.Enable = 'off';
            app.MinBiasVEditFieldLabel = uilabel(app.Panel_3);
            app.MinBiasVEditFieldLabel.Enable = 'off';
            app.MinBiasVEditFieldLabel.Text = 'Min Bias (V)';
            app.MaxBiasVEditFieldLabel = uilabel(app.Panel_3);
            app.MaxBiasVEditFieldLabel.Enable = 'off';
            app.MaxBiasVEditFieldLabel.Text = 'Max Bias (V)';
            app.MaxBiasVEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaxBiasVEditField.ValueChangedFcn = createCallbackFcn(app, @SelectBiasMode, true);
            app.MaxBiasVEditField.Enable = 'off';
            app.MaxBiasVEditField.Value = 0.5;
            app.NumberofPointsEditFieldLabel = uilabel(app.Panel_3);
            app.NumberofPointsEditFieldLabel.Enable = 'off';
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.Panel_3, 'numeric');
            app.NumberofPointsEditField.Limits = [1 Inf];
            app.NumberofPointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofPointsEditField.ValueChangedFcn = createCallbackFcn(app, @SelectBiasMode, true);
            app.NumberofPointsEditField.Enable = 'off';
            app.NumberofPointsEditField.Value = 10;
            app.BiasValueVEditFieldLabel = uilabel(app.Panel_3);
            app.BiasValueVEditFieldLabel.Text = 'Bias Value (V)';
            app.BiasValueVEditField = uieditfield(app.Panel_3, 'numeric');
            app.BiasValueVEditField.ValueChangedFcn = createCallbackFcn(app, @SelectBiasMode, true);
            app.SequenceSwitchLabel = uilabel(app.Panel_3);
            app.SequenceSwitchLabel.Text = 'Sequence';
            app.SequenceSwitch = uiswitch(app.Panel_3, 'toggle');
            app.SequenceSwitch.Items = {'In Order', 'Random'};
            app.SequenceSwitch.Value = 'In Order';
            app.VoltageStartEditField_3Label = uilabel(app.Panel_3);
            app.VoltageStartEditField_3Label.Text = 'Voltage Start';
            app.VoltageStartEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.VoltageStartEditField_3.Value = -1.5;
            app.VoltageEndEditField_3Label = uilabel(app.Panel_3);
            app.VoltageEndEditField_3Label.Text = 'Voltage End';
            app.VoltageEndEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.VoltageEndEditField_3.Value = 1.5;
            app.VoltageStepEditField_3Label = uilabel(app.Panel_3);
            app.VoltageStepEditField_3Label.Text = 'Voltage Step';
            app.VoltageStepEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.VoltageStepEditField_3.Limits = [0 Inf];
            app.VoltageStepEditField_3.Value = 0.05;
            app.ComplianceAEditField_4Label = uilabel(app.Panel_3);
            app.ComplianceAEditField_4Label.Text = 'Compliance (A)';
            app.ComplianceAEditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.ComplianceAEditField_4.Value = 0.02;
            app.NumberofMeasurementsEditField_2Label = uilabel(app.Panel_3);
            app.NumberofMeasurementsEditField_2Label.Text = 'Number of Measurements';
            app.NumberofMeasurementsEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.NumberofMeasurementsEditField_2.Limits = [1 Inf];
            app.NumberofMeasurementsEditField_2.Value = 1;
            app.Compliance2ndEditField = uieditfield(app.Panel_3, 'numeric');
            app.Compliance2ndEditField.Value = 0.1;
            app.Panel_5 = uipanel(app.ExternalBiasTab);
            app.FolderEditField_3Label = uilabel(app.Panel_5);
            app.FolderEditField_3Label.Text = 'Folder';
            app.FolderEditField_3 = uieditfield(app.Panel_5, 'text');
            app.FileNameEditField_3Label = uilabel(app.Panel_5);
            app.FileNameEditField_3Label.Text = 'File Name';
            app.FileNameEditField_3 = uieditfield(app.Panel_5, 'text');
            app.ChooseFolderButton_3 = uibutton(app.Panel_5, 'push');
            app.ChooseFolderButton_3.ButtonPushedFcn = createCallbackFcn(app, @GetFolder, true);
            app.ChooseFolderButton_3.Text = 'Choose Folder';
            app.SaveButton_2 = uibutton(app.Panel_5, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveExternalBias, true);
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.DescriptionTextArea_2Label = uilabel(app.Panel_5);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionEditField = uitextarea(app.Panel_5);
            app.Panel_6 = uipanel(app.ExternalBiasTab);
            app.StatusEditField_2Label = uilabel(app.Panel_6);
            app.StatusEditField_2Label.Text = 'Status:';
            app.StatusEditField_2 = uieditfield(app.Panel_6, 'text');
            app.StatusEditField_2.Editable = 'off';
            app.StatusEditField_2.Value = 'Disconnected';
            app.RunButton = uibutton(app.Panel_6, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunExternalBias, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.Connectto2ndSMUButton = uibutton(app.Panel_6, 'state');
            app.Connectto2ndSMUButton.ValueChangedFcn = createCallbackFcn(app, @Connect2ndSMU, true);
            app.Connectto2ndSMUButton.Enable = 'off';
            app.Connectto2ndSMUButton.Text = 'Connect to 2nd SMU';
            app.LightSwitchLabel = uilabel(app.Panel_6);
            app.LightSwitchLabel.Text = 'Light';
            app.LightSwitch = uiswitch(app.Panel_6, 'toggle');
            app.LightSwitch.Items = {'Fixed', 'Auto'};
            app.LightSwitch.Enable = 'off';
            app.LightSwitch.Value = 'Fixed';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IVgetter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end